classdef TSADPlatform < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TSADPlatformUIFigure            matlab.ui.Figure
        SettingsMenu                    matlab.ui.container.Menu
        ThresholdSelectionMenu          matlab.ui.container.Menu
        DynamicThresholdMenu            matlab.ui.container.Menu
        EnableParallelModeMenu          matlab.ui.container.Menu
        MainPanel                       matlab.ui.container.TabGroup
        DatasetPreparationTab           matlab.ui.container.Tab
        DatasetPreparationPanel         matlab.ui.container.Panel
        D_DatasetProcessingPanel        matlab.ui.container.Panel
        D_AugmentationIntensityLabel    matlab.ui.control.Label
        D_AugmentationModeDropDown      matlab.ui.control.DropDown
        AugmentationModeDropDownLabel   matlab.ui.control.Label
        D_PreprocessingMethodDropDown   matlab.ui.control.DropDown
        PreprocessingMethodLabel        matlab.ui.control.Label
        D_AugmentationIntensitySlider   matlab.ui.control.Slider
        D_AugmentedTrainingCheckBock    matlab.ui.control.CheckBox
        D_RatioTestSwitchLabel          matlab.ui.control.Label
        D_RatioTestSwitchEditField      matlab.ui.control.NumericEditField
        D_SplitTestSetForDynamicSwitchCheckBox  matlab.ui.control.CheckBox
        D_RatioTestValLabel             matlab.ui.control.Label
        D_UseAnomalousValidationSetCheckBox  matlab.ui.control.CheckBox
        D_RatioTestValEditField         matlab.ui.control.NumericEditField
        D_DatasetSelectionPanel         matlab.ui.container.Panel
        D_DatasetFolderEditField        matlab.ui.control.EditField
        D_DatasetFolderLabel            matlab.ui.control.Label
        D_BrowseDatasetButton           matlab.ui.control.Button
        D_LoadDataButton                matlab.ui.control.Button
        D_DatasetAttributesUITable      matlab.ui.control.Table
        D_DatasetAttributesLabel        matlab.ui.control.Label
        D_SelectedFileTestValDropDown   matlab.ui.control.DropDown
        SelectedFileLabel_5             matlab.ui.control.Label
        D_SelectedChannelTestValDropDown  matlab.ui.control.DropDown
        SelectedChannelLabel_5          matlab.ui.control.Label
        D_SelectedFileTestDropDown      matlab.ui.control.DropDown
        SelectedFileLabel_4             matlab.ui.control.Label
        D_SelectedChannelTestDropDown   matlab.ui.control.DropDown
        SelectedChannelLabel_4          matlab.ui.control.Label
        D_SelectedFileTrainDropDown     matlab.ui.control.DropDown
        SelectedFileLabel_3             matlab.ui.control.Label
        D_SelectedChannelTrainDropDown  matlab.ui.control.DropDown
        SelectedChannelLabel_3          matlab.ui.control.Label
        D_PreprocessedTestValDatasetPreviewUIAxes  matlab.ui.control.UIAxes
        D_PreprocessedTestingDatasetPreviewUIAxes  matlab.ui.control.UIAxes
        D_PreprocessedTrainingDatasetPreviewUIAxes  matlab.ui.control.UIAxes
        TrainingTab                     matlab.ui.container.Tab
        TrainingPanel                   matlab.ui.container.Panel
        T_PreviewPanel                  matlab.ui.container.Panel
        T_PreviewShowingLabel           matlab.ui.control.Label
        T_PreviewHyperparametersUITable  matlab.ui.control.Table
        T_PreviewGeneralInformationUITable  matlab.ui.control.Table
        T_NoModelLoadedforPreviewLabel  matlab.ui.control.Label
        T_PreviewUIAxes                 matlab.ui.control.UIAxes
        T_ModelPreviewLabel             matlab.ui.control.Label
        T_TrainingPanel                 matlab.ui.container.Panel
        T_OptimizeAllButton             matlab.ui.control.Button
        T_TrainingPlotsCheckBox         matlab.ui.control.CheckBox
        T_OptimizeSelectionButton       matlab.ui.control.Button
        T_TrainAllModelsButton          matlab.ui.control.Button
        T_TrainSelectedModelsButton     matlab.ui.control.Button
        T_ModelsPanel                   matlab.ui.container.Panel
        T_ExportConfigButton            matlab.ui.control.Button
        T_LoadModelsFromFileButton      matlab.ui.control.Button
        T_QuickLoadAllModelsButton      matlab.ui.control.Button
        T_ClearModelsButton             matlab.ui.control.Button
        T_AddModelsManuallyButton       matlab.ui.control.Button
        T_ModelsListBox                 matlab.ui.control.ListBox
        DetectionEvaluationTab          matlab.ui.container.Tab
        DetectionEvaluationPanel        matlab.ui.container.Panel
        DE_DetectionPanel               matlab.ui.container.Panel
        DE_UpdateThresholdOnlyCheckBox  matlab.ui.control.CheckBox
        DE_RunDetectionForAllButton     matlab.ui.control.Button
        DE_GetComputationTimeCheckBox   matlab.ui.control.CheckBox
        DE_RunForSelectedModelsOnAllFilesButton  matlab.ui.control.Button
        DE_RunForAllModelsOnAllFilesButton  matlab.ui.control.Button
        DE_ThresholdDropDown            matlab.ui.control.DropDown
        ThresholdDropDownLabel          matlab.ui.control.Label
        DE_TestDataFileDropDown         matlab.ui.control.DropDown
        SelectfaultyDataLabel           matlab.ui.control.Label
        DE_AnomalyPaddingSlider         matlab.ui.control.Slider
        DE_WindowSizeSlider             matlab.ui.control.Slider
        DE_ZRangeSlider                 matlab.ui.control.Slider
        DE_AnomalyPaddingLabel          matlab.ui.control.Label
        DE_WindowSizeLabel              matlab.ui.control.Label
        DE_ZRangeLabel                  matlab.ui.control.Label
        DE_OfflineDetectionLabel        matlab.ui.control.Label
        DE_RunDetectionForSelectedButton  matlab.ui.control.Button
        DE_DetectionTabGroup            matlab.ui.container.TabGroup
        DE_PlotsTab                     matlab.ui.container.Tab
        DE_DetectionPerformanceMetricsLabel  matlab.ui.control.Label
        DE_DetectionShownForLabel       matlab.ui.control.Label
        DE_PlotsScoresUITable           matlab.ui.control.Table
        DE_PlotsDataUIAxes              matlab.ui.control.UIAxes
        DE_PlotsAnomalyScoresUIAxes     matlab.ui.control.UIAxes
        DE_TableTab                     matlab.ui.container.Tab
        DE_ScoresLegendLabel            matlab.ui.control.Label
        DE_ScoresColumnwiseMaximaLabel  matlab.ui.control.Label
        DE_ScoresRowwiseMaximaLabel     matlab.ui.control.Label
        DE_ScoresExportTableButton      matlab.ui.control.Button
        DE_ScoresUITable                matlab.ui.control.Table
        DE_AverageScoresTab             matlab.ui.container.Tab
        DE_AverageScoresLegendLabel     matlab.ui.control.Label
        DE_AverageScoresColumnwiseMaximaLabel  matlab.ui.control.Label
        DE_AverageScoresRowwiseMaximaLabel  matlab.ui.control.Label
        DE_AverageScoresExportTableButton  matlab.ui.control.Button
        DE_AverageScoresUITable         matlab.ui.control.Table
        DE_ComputationTimeTab           matlab.ui.container.Tab
        DE_CompTimeMetricSelectionButton  matlab.ui.control.Button
        DE_CompTimeUIAxes               matlab.ui.control.UIAxes
        DE_TinyDataPreviewUIAxes        matlab.ui.control.UIAxes
        DE_TrainedModelsPanel           matlab.ui.container.Panel
        DE_SortByDropDown               matlab.ui.control.DropDown
        SortbyLabel                     matlab.ui.control.Label
        DE_ImportTrainedModelsButton    matlab.ui.control.Button
        DE_ExportTrainedModels          matlab.ui.control.Button
        DE_TrainedModelsListBox         matlab.ui.control.ListBox
        SimulinkDetectionTab            matlab.ui.container.Tab
        SimulinkDetectionPanel          matlab.ui.container.Panel
        SD_DetectionPanel               matlab.ui.container.Panel
        SD_FaultyDataSelectionDropDown  matlab.ui.control.DropDown
        SelectfaultyDataLabel_2         matlab.ui.control.Label
        SD_DetectionShownForLabel       matlab.ui.control.Label
        SD_ThresholdDropDown            matlab.ui.control.DropDown
        ThresholdDropDownLabel_4        matlab.ui.control.Label
        SD_OpenmodelCheckBox            matlab.ui.control.CheckBox
        SD_RunDetectionForSelectedButton  matlab.ui.control.Button
        SD_TrueAnomaliesUIAxes          matlab.ui.control.UIAxes
        SD_FoundAnomaliesUIAxes         matlab.ui.control.UIAxes
        SD_TrainedModelsPanel           matlab.ui.container.Panel
        SD_ImportTrainedModelsButton    matlab.ui.control.Button
        SD_ExportTrainedModels          matlab.ui.control.Button
        SD_TrainedModelsListBox         matlab.ui.control.ListBox
        cDynamicSwitchTab               matlab.ui.container.Tab
        DynamicSwitchPanel              matlab.ui.container.Panel
        DS_SelectedFileTestSwitchDropDown  matlab.ui.control.DropDown
        SelectedFileLabel_6             matlab.ui.control.Label
        DS_SelectedChannelTestSwitchDropDown  matlab.ui.control.DropDown
        SelectedChannelLabel_6          matlab.ui.control.Label
        DS_ExportButton                 matlab.ui.control.Button
        DS_TrainingAndEvaluationPanel   matlab.ui.container.Panel
        DS_TestingDataPredictionsLabel  matlab.ui.control.Label
        DS_TestingDataPredictionsUITable  matlab.ui.control.Table
        DS_TrainingDataLabelsLabel      matlab.ui.control.Label
        DS_TrainingDataLabelsUITable    matlab.ui.control.Table
        DS_MetricDropDown               matlab.ui.control.DropDown
        ScoreforAutolabelingLabel       matlab.ui.control.Label
        DS_TrainClassifierButton        matlab.ui.control.Button
        DS_RunEvaluationsButton         matlab.ui.control.Button
        DS_ResultsLabel                 matlab.ui.control.Label
        DS_ResultsUITable               matlab.ui.control.Table
        DE_PreprocessedTestSwitchDatasetPreviewUIAxes  matlab.ui.control.UIAxes
        AutoRunTab                      matlab.ui.container.Tab
        AutoRunPanel                    matlab.ui.container.Panel
        AR_ExportTableButton            matlab.ui.control.Button
        AR_ResultsLabel                 matlab.ui.control.Label
        AR_ResultsUITable               matlab.ui.control.Table
        AR_AutoTrainingAndEvaluationPanel  matlab.ui.container.Panel
        AR_GetComputationTimeCheckBox   matlab.ui.control.CheckBox
        AR_VisualizeDataButton          matlab.ui.control.Button
        AR_AugmentationModeDropDown     matlab.ui.control.DropDown
        AugmentationModeDropDownLabel_2  matlab.ui.control.Label
        AR_PreprocessingMethodDropDown  matlab.ui.control.DropDown
        PreprocessingMethodLabel_2      matlab.ui.control.Label
        AR_IntensityLabel               matlab.ui.control.Label
        AR_IntensitySlider              matlab.ui.control.Slider
        AR_AugmentedTrainingCheckBock   matlab.ui.control.CheckBox
        AR_TrainingPlotsCheckBox        matlab.ui.control.CheckBox
        AR_RatioTestValLabel            matlab.ui.control.Label
        AR_UseAnomalousValidationSetCheckBox  matlab.ui.control.CheckBox
        AR_RatioTestValEditField        matlab.ui.control.NumericEditField
        AR_DatasetFolderEditField       matlab.ui.control.EditField
        AR_DatasetFolderLabel           matlab.ui.control.Label
        AR_BrowseDatasetButton          matlab.ui.control.Button
        AR_RunEvaluationButton          matlab.ui.control.Button
        T_ContextMenu                   matlab.ui.container.ContextMenu
        T_DeleteMenu                    matlab.ui.container.Menu
        T_ShowModelParametersMenu       matlab.ui.container.Menu
        T_ChangeScoringFunctionMenu     matlab.ui.container.Menu
        T_UpdateStaticThresholdsMenu    matlab.ui.container.Menu
        DE_ContextMenu                  matlab.ui.container.ContextMenu
        DE_ShowDetectionMenu            matlab.ui.container.Menu
    end

    
    properties (Access = private)
        %% App Status Information
        info_dataLoaded = false                     % True if data was loaded
        info_trainDataLoaded = false;               % True if train data was loaded
        info_testDataLoaded = false;                % True if test data was loaded
        info_numTrainFiles = 0;                     % Number of training files
        info_numTestFiles = 0;                      % Number of all testing files
        info_numChannels = false;                   % Number of channels in dataset
        info_detectionRun = false;                  % True if detection has been done
        info_dimensionMismatchDetected = false;     % true if dataset diemsnion doesn't match dimension of model in runDetectionFor function.
                                                    % This is only used for
                                                    % uialert message handling
        %%

        %% Data
        data_channelNames                           % Names of the channels

        data_dataTrainRaw                           % Initial fault free raw data
        data_dataTestRaw                            % Initial faulty raw data
        

        data_fileNamesTrain                         % Array of filenames of the training data
        data_fileNamesTestAll                       % Array of filenames of the entire testing dataset
        data_fileNamesTest                          % Array of filenames of the final testing data (after splitting the dataset) 
        data_fileNamesTestVal                       % Array of filenames of the anomalous validation data
        data_fileNamesTestSwitch                    % Array of filenames of the dynamic switch data
        data_allTestFileNames                       % Array of filenames of all test files without the dynamic switch files

        data_labelsTrain                            % Training labels   
        data_labelsTestAll                          % Labels for all test files
        data_labelsTest                             % Labels for final testing data (after splitting the dataset) 
        data_labelsTestVal                          % Labels for anomalous validation data
        data_labelsTestSwitch                       % Labels for dynamic switch data
        data_allTestLabels                          % Labels for all test files without the dynamic switch labels

        data_dataTrain                              % Preprocessed fault free data
        data_dataTestAll                            % All testing data
        data_dataTest                               % Final testing data (after splitting the dataset) 
        data_dataTestVal                            % Anomalous validation data
        data_dataTestSwitch                         % Dynamic switch test data
        data_allTestData                            % All test data without the dynamic switch data
        
        % These timestamps aren't used in the platform, but could be of interest for
        % future upgrades
        data_timestampsTrain                        % Timestamps of training data
        data_timestampsTestAll                      % Timestamps of all test data
        data_timestampsTest                         % Timestamps of the final testing data (after splitting the dataset) 
        data_timestampsTestSwitch                   % Timestamps of the dynamic switch data
        data_timestampsTestVal                      % Timestamps of the anoamlous validation data
        data_allTestTimestamps                      % Timestamps of all test files without the dynmaic switch data

        % dataset features
        data_maximum                                % Maximum values of data
        data_minimum                                % Minimum values of data

        data_mu                                     % Means of training data
        data_sigma                                  % Standard deviations data
        %%

        %% Models
        trainedModels = struct();                   % Trained models struct

        %% Settings
        settings_parallelEnabled = false;           % If true, training will be run in parallel
        settings_thresholds = ["bestFscorePointwise"]; % Selected thresholds
        settings_dynamicThreshold                   % Dynamic threshold settings

        %% Detection
        currentDetection_anomalyScores              % Currently shown anomaly scores in detection tab
        currentDetection_YTest                      % YTest for currently shown prediction
        currentDetection_labels                     % Labels of currently shown prediction
        currentDetection_trainedModel               % Model currently selected for detection on the detection panel

        
        fullScores = struct();                      % All scores for all tested files
        averageScores                               % Average scores for all models for all files
        
        storedTestAnomalyScores = struct();         % Stores all test anomaly scores, Ytest and labels for each file and model the detection has been run for
        storedThresholdValues = struct();           % Stores all already computed thresholds for each file and model to save computational effort

        compTimeMetrics = ["F1 Score (point-wise)"]; % Selected metrics shown in the computation time plot
        %%
        
        %% Dynamic Switch
        ds_classifier                               % Classification network for the dynamic switch
        ds_bestModels = struct();                   % Labels for training the dynamic switch

        %% Popup Apps
        popup_ModelSelection                        % Popup for selecting the Deep Neural Network models
        popup_Optimization                          % Popup for optimizing models
        popup_ThresholdSelection                    % Popup for selecting thresholds
        popup_OptimizationSettings                  % Popup for the optimization settings
        popup_ScoringFunctionSelection              % Pupup for changing the scoring function of models
        popup_CompTimeMetricSelection               % Popup for selecting the computation time metrics
        popup_DynamicThresholdSettings              % Popup for configuring the dynmaic threshold
        popup_DatasetVisualization                  % Popup for visualizing the global transformation
        %%
    end
    
    properties (Access = public)
    end
    
    methods (Access = private)
%% Toggle functions for disabling/enabling elements
        
        function toggleDynamicSwitchPanel(app, state)
            % Toggles the dynamic switch panel

            if state
                app.DS_TrainClassifierButton.Enable = "on";
                app.DS_RunEvaluationsButton.Enable = "on";
            else
                app.DS_TrainClassifierButton.Enable = "off";
                app.DS_RunEvaluationsButton.Enable = "off";
            end
        end

        function toggleDynamicThresholdSliders(app, threshold)
            % Toggles the sliders for configuring the dynamic threshold

            if strcmp(threshold, "Dynamic")
                app.DE_AnomalyPaddingSlider.Enable = "on";
                app.DE_AnomalyPaddingLabel.Enable = "on";
                app.DE_WindowSizeSlider.Enable = "on";
                app.DE_WindowSizeLabel.Enable = "on";
                app.DE_ZRangeSlider.Enable = "on";
                app.DE_ZRangeLabel.Enable = "on";
            else
                app.DE_AnomalyPaddingSlider.Enable = "off";
                app.DE_AnomalyPaddingLabel.Enable = "off";
                app.DE_WindowSizeSlider.Enable = "off";
                app.DE_WindowSizeLabel.Enable = "off";
                app.DE_ZRangeSlider.Enable = "off";
                app.DE_ZRangeLabel.Enable = "off";
            end
        end

%% Dataset preview plotting functions
        
        function plotLoadedData(app, dataset, file_idx, channel_idx)
            % Plotting of dataset

            switch dataset
                case "train"
                    axes = app.D_PreprocessedTrainingDatasetPreviewUIAxes;
                    data = app.data_dataTrain{file_idx}(:, channel_idx);
                case "test"
                    axes = app.D_PreprocessedTestingDatasetPreviewUIAxes;
                    data = app.data_dataTest{file_idx}(:, channel_idx);
                case "test-val"
                    axes = app.D_PreprocessedTestValDatasetPreviewUIAxes;
                    data = app.data_dataTestVal{file_idx}(:, channel_idx);
                case "test-switch"
                    axes = app.DE_PreprocessedTestSwitchDatasetPreviewUIAxes;
                    data = app.data_dataTestSwitch{file_idx}(:, channel_idx);
            end
            
            cla(axes);
            plot(axes, data);
        end

        function updatePreviewData(app, dataset)
            % Update plots of dataset

            switch dataset
                case "train"
                    file = app.D_SelectedFileTrainDropDown.Value;
                    [~, file_idx] = ismember(file, app.D_SelectedFileTrainDropDown.Items);
                    channel = app.D_SelectedChannelTrainDropDown.Value;
                    [~, channel_idx] = ismember(channel, app.D_SelectedChannelTrainDropDown.Items);
                case "test"
                    file = app.D_SelectedFileTestDropDown.Value;
                    [~, file_idx] = ismember(file, app.D_SelectedFileTestDropDown.Items);
                    channel = app.D_SelectedChannelTestDropDown.Value;
                    [~, channel_idx] = ismember(channel, app.D_SelectedChannelTestDropDown.Items);
                case "test-val"
                    file = app.D_SelectedFileTestValDropDown.Value;
                    [~, file_idx] = ismember(file, app.D_SelectedFileTestValDropDown.Items);
                    channel = app.D_SelectedChannelTestValDropDown.Value;
                    [~, channel_idx] = ismember(channel, app.D_SelectedChannelTestValDropDown.Items);
                case "test-switch"
                    file = app.DS_SelectedFileTestSwitchDropDown.Value;
                    [~, file_idx] = ismember(file, app.DS_SelectedFileTestSwitchDropDown.Items);
                    channel = app.DS_SelectedChannelTestSwitchDropDown.Value;
                    [~, channel_idx] = ismember(channel, app.DS_SelectedChannelTestSwitchDropDown.Items);
            end

            app.plotLoadedData(dataset, file_idx, channel_idx);
            app.createPreviewLegend(dataset, file_idx);
        end

        function createPreviewLegend(app, dataset, file_idx)
            % Creates legend for dataset axes

            switch dataset
                case "train"
                    axes = app.D_PreprocessedTrainingDatasetPreviewUIAxes;
                    anomalies_indices = getIndexes(app.data_labelsTrain{file_idx});
                case "test"
                    axes = app.D_PreprocessedTestingDatasetPreviewUIAxes;
                    anomalies_indices = getIndexes(app.data_labelsTest{file_idx});
                case "test-val"
                    axes = app.D_PreprocessedTestValDatasetPreviewUIAxes;
                    anomalies_indices = getIndexes(app.data_labelsTestVal{file_idx});
                case "test-switch"
                    axes = app.DE_PreprocessedTestSwitchDatasetPreviewUIAxes;
                    anomalies_indices = getIndexes(app.data_labelsTestSwitch{file_idx});
            end
            
            YLims = get(axes, "YLim");
            limits = [];
            for i = 1:size(anomalies_indices, 2)
                limits(1, i) = YLims(1);
                limits(2, i) = YLims(1);
                limits(3, i) = YLims(2);
                limits(4, i) = YLims(2);
            end
            
            if ~isempty(anomalies_indices) && ~isempty(limits)
                patch(axes, anomalies_indices, limits, "red", "EdgeColor", "none", "FaceAlpha", 0.3);
                legend(axes, "Data", "Anomalies");
            else
                legend(axes, "Data");
            end
        end   

%% Helper functions

        function highlightTableMaxima(~, UITable, type, rowIncrement)
            % Highlights maxima in table

            if ~exist("rowIncrement", "var")
                rowIncrement = 0;
            end

            scoreMatrix = UITable.Data{(1 + rowIncrement):end, 2:end};

            if strcmp(type, "row-wise") || strcmp(type, "all")
                % Add bold highlight for rowise maxima
                styleBold = uistyle;
                styleBold.FontWeight = "bold";
                
                rows_max_rowwise = [];
                columns_max_rowwise = [];
    
                for i = 1:size(scoreMatrix, 1)
                    [~, col] = find(scoreMatrix(i, :) == max(scoreMatrix(i, :), [], 2));
                    
                    for j = 1:numel(col)
                        rows_max_rowwise = [rows_max_rowwise; i];
                        columns_max_rowwise = [columns_max_rowwise; col(j) + 1];
                    end
                end
                
                if ~isempty(rows_max_rowwise) && ~isempty(columns_max_rowwise)
                    rows_max_rowwise = rows_max_rowwise + rowIncrement;
                    addStyle(UITable, styleBold, "cell", [rows_max_rowwise, columns_max_rowwise]);
                end
            end

            if strcmp(type, "column-wise") || strcmp(type, "all")
                % Add green background highlight for column-wise maxima
                styleGreen = uistyle;
                styleGreen.BackgroundColor = "#86F2BC";
    
                rows_max_columnwise = [];
                columns_max_columnwise = [];
    
                for i = 1:size(scoreMatrix, 2)
                    [row, ~] = find(scoreMatrix(:, i) == max(scoreMatrix(:, i), [], 1));
                    
                    for j = 1:numel(row)
                        rows_max_columnwise = [rows_max_columnwise; row(j)];
                        columns_max_columnwise = [columns_max_columnwise; i + 1];
                    end
                end
                
                if ~isempty(rows_max_columnwise) && ~isempty(columns_max_columnwise)
                    rows_max_columnwise = rows_max_columnwise + rowIncrement;
                    addStyle(UITable, styleGreen, "cell", [rows_max_columnwise, columns_max_columnwise]);
                end
            end
        end
        
%% Dataset preparation panel functions
        
        function clearDatasetPlots(app)
            % Reset data related plots

            cla(app.D_PreprocessedTrainingDatasetPreviewUIAxes);
            cla(app.D_PreprocessedTestingDatasetPreviewUIAxes);
            cla(app.D_PreprocessedTestValDatasetPreviewUIAxes);
            cla(app.DE_PreprocessedTestSwitchDatasetPreviewUIAxes);
        end

        function clearDynamicSwitchData(app)
            % Clear data relvenat for dynamic switch

            app.ds_classifier = [];
            app.ds_bestModels = [];
            app.DS_TrainingDataLabelsUITable.Data = [];
            app.DS_TestingDataPredictionsUITable.Data = [];
        end

        function runPreprocessing(app, preprocessingEnabled)
            % Processes the entire dataset and splits it into specified
            % subsets
            % preprocessingEnabled is false when just the dataset
            % splitting is updated to avoid unnecessary computation

            if ~app.info_dataLoaded
                return;
            end
           

            if preprocessingEnabled
                % All preprocessing steps except for dataset splitting
                % tasks are executed in here. preprocessingEnabled must be
                % set to true to enable this!
                
                preprocMethod = app.D_PreprocessingMethodDropDown.Value;
                
                % Preprocessing
                [app.data_dataTrain, app.data_allTestData, ~] = preprocessData(app.data_dataTrainRaw, ...
                                                                               app.data_dataTestRaw, ...
                                                                               preprocMethod, false, []);
                
                % Augmentation
                if ~strcmp(app.D_AugmentationModeDropDown.Value, "none")
                    [app.data_dataTrain, app.data_allTestData] = augmentData(app.data_dataTrain, ...
                                                                             app.data_allTestData, ...
                                                                             app.D_AugmentationModeDropDown.Value, ...
                                                                             app.D_AugmentationIntensitySlider.Value, ...
                                                                             app.D_AugmentedTrainingCheckBock.Value);
                end
            end


            if app.D_SplitTestSetForDynamicSwitchCheckBox.Value && app.info_numTestFiles > 1
                ratioSwitch = app.D_RatioTestSwitchEditField.Value;
                [app.data_dataTestAll, app.data_labelsTestAll, app.data_fileNamesTestAll, ...
                    app.data_dataTestSwitch, app.data_labelsTestSwitch, app.data_fileNamesTestSwitch] = splitDatasetForDynamicSwitch(app.data_allTestData, ...
                                                                                                                                 app.data_allTestLabels, ...
                                                                                                                                 app.data_allTestFileNames, ...
                                                                                                                                 ratioSwitch);
            else
                app.data_dataTestAll = app.data_allTestData;
                app.data_labelsTestAll = app.data_allTestLabels;
                app.data_fileNamesTestAll = app.data_allTestFileNames;

                app.data_dataTestSwitch = [];
                app.data_labelsTestSwitch = [];
                app.data_fileNamesTestSwitch = [];
            end

            if app.D_UseAnomalousValidationSetCheckBox.Value
                ratioTestVal = app.D_RatioTestValEditField.Value;
            else
                ratioTestVal = 0;
            end

            
            if ~isempty(app.data_dataTestAll)
                [app.data_dataTest, ...
                    app.data_labelsTest, ...
                    app.data_fileNamesTest, ...
                    app.data_dataTestVal, ...
                    app.data_labelsTestVal, ...                    
                    app.data_fileNamesTestVal] = splitTestVal(app.data_dataTestAll, ...
                                                          app.data_labelsTestAll, ...
                                                          app.data_fileNamesTestAll, ...
                                                          ratioTestVal);
            else
                app.data_dataTest = [];
                app.data_labelsTest = [];
                app.data_fileNamesTest = [];
                app.data_dataTestVal = [];
                app.data_labelsTestVal = [];
                app.data_fileNamesTestVal = [];
            end

            if ~isempty(app.data_fileNamesTrain)
                app.D_SelectedFileTrainDropDown.Items = app.data_fileNamesTrain;
            end

            if ~isempty(app.data_fileNamesTest)
                app.D_SelectedFileTestDropDown.Items = app.data_fileNamesTest;
            end

            if ~isempty(app.data_fileNamesTestVal)
                app.D_SelectedFileTestValDropDown.Items = app.data_fileNamesTestVal;
            else
                app.D_SelectedFileTestValDropDown.Items = {};
            end

            if ~isempty(app.data_fileNamesTestSwitch)
                app.DS_SelectedFileTestSwitchDropDown.Items = app.data_fileNamesTestSwitch;
            else
                app.DS_SelectedFileTestSwitchDropDown.Items = {};
            end
            
            if ~isempty(app.data_fileNamesTest)
                app.DE_TestDataFileDropDown.Items = app.data_fileNamesTest;
                app.SD_FaultyDataSelectionDropDown.Items = app.data_fileNamesTest;

                app.updateTinyDataPreview();
    
                app.initScores();
                app.clearDetectionPlots();
                app.updateDetectionPanel();
            else
                app.DE_TestDataFileDropDown.Items = {};
                app.SD_FaultyDataSelectionDropDown.Items = {};

                cla(app.DE_TinyDataPreviewUIAxes);
            end
            
            if ~isempty(app.data_dataTrain)
                app.updatePreviewData("train");
            end

            if ~isempty(app.data_dataTest)
                app.updatePreviewData("test");
            end

            if ~isempty(app.data_dataTestVal)
                app.D_SelectedChannelTestValDropDown.Enable = "on";
                app.D_SelectedFileTestValDropDown.Enable = "on";

                app.updatePreviewData("test-val");
            else
                app.D_SelectedChannelTestValDropDown.Enable = "off";
                app.D_SelectedFileTestValDropDown.Enable = "off";

                cla(app.D_PreprocessedTestValDatasetPreviewUIAxes);
            end

            if ~isempty(app.data_dataTestSwitch)
                app.DynamicSwitchPanel.Enable = "on";

                app.updatePreviewData("test-switch");
            else
                app.DynamicSwitchPanel.Enable = "off";

                cla(app.DE_PreprocessedTestSwitchDatasetPreviewUIAxes);
            end
        end
        
        function showDatasetParameters(app)
            % Initialize channel selection dropdown menus for
            % preview

            app.D_SelectedChannelTrainDropDown.Items = app.data_channelNames;
            app.D_SelectedChannelTestDropDown.Items = app.data_channelNames;
            app.D_SelectedChannelTestValDropDown.Items = app.data_channelNames;
            app.DS_SelectedChannelTestSwitchDropDown.Items = app.data_channelNames;
            
            % Calc statistical data features
            if ~isempty(app.data_dataTrainRaw)
                fullData = [];
                for i = 1:numel(app.data_dataTrainRaw)
                    fullData = [fullData; app.data_dataTrainRaw{i}];
                end
            elseif ~isempty(app.data_dataTestRaw)
                fullData = [];
                for i = 1:numel(app.data_dataTestRaw)
                    fullData = [fullData; app.data_dataTestRaw{i}];
                end
            end

            [~, app.data_mu, app.data_sigma] = zscore(fullData, 0, 1);
            app.data_minimum = min(fullData, [], 1);
            app.data_maximum = max(fullData, [], 1);

            % Show features of data
            app.D_DatasetAttributesUITable.Data = [app.data_channelNames', ...
                                                   app.data_minimum', ...
                                                   app.data_maximum', ...
                                                   app.data_mu', ...
                                                   app.data_sigma'];
        end

%% Training panel functions
        
        function runTraining(app, models)
            % Trains models
            
            if app.T_TrainingPlotsCheckBox.Value
                trainingPlots = "training-progress";
            else
                trainingPlots = "none";
            end

            trainedModels_tmp = trainingWrapper(models, ...
                                                app.data_dataTrain, ...
                                                app.data_labelsTrain, ...
                                                app.data_dataTestVal, ...
                                                app.data_labelsTestVal, ...
                                                app.settings_thresholds, ...
                                                trainingPlots, ...
                                                app.settings_parallelEnabled);

            app.initNewTrainedModels(trainedModels_tmp);
            app.updateDetectionPanel();
        end

        function runOptimizations(app, models, iterations, metric, threshold, trainingPlots)
            % Runs the optimizations for all selected models

            configOptFileName = "tsad_platform_config_optimization.json";

            optimizedModelOptions = autoOptimization(models, ...
                                                     app.data_dataTrain, ...
                                                     app.data_labelsTrain, ...
                                                     app.data_dataTestVal, ...
                                                     app.data_labelsTestVal, ...
                                                     app.data_dataTest, ...
                                                     app.data_labelsTest, ...
                                                     configOptFileName, ...
                                                     metric, ...
                                                     threshold, ...
                                                     app.settings_dynamicThreshold, ...
                                                     iterations, ...
                                                     trainingPlots, ...
                                                     app.settings_parallelEnabled);
            
            % Convert models to optimized versions in lists        
            for i = 1:numel(optimizedModelOptions)
                app.convertModelOptionsInList(optimizedModelOptions(i).modelOptions);
            end

            % Train optimized models
            if ~isempty(optimizedModelOptions)
                app.runTraining(optimizedModelOptions);
            end
        end

        function initNewTrainedModels(app, newTrainedModels)
            % Adds new trained models to pool of trained models

            trainedModelIds = fieldnames(newTrainedModels);
            for i = 1:numel(trainedModelIds)
                modelId = trainedModelIds{i};
                modelLabel = newTrainedModels.(trainedModelIds{i}).modelOptions.label;
                app.removePreviousDetectionsOfModel(modelId, modelLabel);
                app.addModelToScores(modelLabel);
                app.trainedModels.(modelId) = newTrainedModels.(modelId);
            end
        end    
        
        function updateThresholdsForSelection(app, models)
            % Re-calculates the static thresholds (only for models which
            % are already trained).

            for i = 1:numel(models)
                modelOptions_tmp = models(i).modelOptions;
                if isfield(app.trainedModels, modelOptions_tmp.id)
                    trainedModel_tmp = app.trainedModels.(modelOptions_tmp.id);

                    if ~strcmp(modelOptions_tmp.learningType, "unsupervised") && ...
                        ~modelOptions_tmp.outputsLabels
                        switch modelOptions_tmp.learningType
                            case "semi-supervised"
                                trainedModel_tmp.staticThresholds = getStaticThresholds(trainedModel_tmp, ...
                                                                                        app.data_dataTestVal, ...
                                                                                        app.data_labelsTestVal, ...
                                                                                        app.settings_thresholds, ...
                                                                                        "anomalous-validation-data");
                            case "supervised"
                                trainedModel_tmp.staticThresholds = getStaticThresholds(trainedModel_tmp, ...
                                                                                        [], [], ...
                                                                                        app.settings_thresholds, ...
                                                                                        "training-data");
                        end
                    end
                    updatedTrainedModels.(modelOptions_tmp.id) = trainedModel_tmp;
                end
            end

            if exist("updatedTrainedModels", "var")
                app.initNewTrainedModels(updatedTrainedModels);
                app.updateDetectionPanel();
            end
        end
        
        function convertModelOptionsInList(app, modelOptions)   
            % Converts modelOptions after running an optimization in
            % listbox on training panel

            for i = 1:numel(app.T_ModelsListBox.ItemsData)
                if strcmp(app.T_ModelsListBox.ItemsData(i).modelOptions.id, modelOptions.id)
                    app.T_ModelsListBox.ItemsData(i).modelOptions = modelOptions;
                    return;
                end
            end
        end
        
        function [modelLabel, modelId, intanceNumber] = getLabelForModel(app, modelName)
            % Gets and unused label and id for the new model and the
            % correct instance number (counter for instances of same kind of model)

            listBox = app.T_ModelsListBox;

            usedInstanceNumbers = [];
            for i = 1:numel(listBox.ItemsData)
                if strcmp(modelName, listBox.ItemsData(i).modelOptions.name)
                    usedInstanceNumbers = [usedInstanceNumbers, listBox.ItemsData(i).modelOptions.instanceNumber];
                end
            end

            intanceNumber = 1;
            while true
                if ismember(intanceNumber, usedInstanceNumbers)
                    intanceNumber = intanceNumber + 1;
                    continue;
                else
                    break;
                end
            end

            modelLabel = sprintf("%s  (%d)", modelName, intanceNumber);
            modelId = sprintf("%s_%d", modelName, intanceNumber);
            modelId = replace(modelId, " ", "_");
            modelId = replace(modelId, "-", "_");
            modelId = replace(modelId, "(", "");
            modelId = replace(modelId, ")", "");
        end
        
        function graph = createModelGraphForPreview(~, modelOptions)
            % Creates the model graph of a DNN

            layers = getLayers(modelOptions, 1, 1);
            a = layers.Connections;
            b = extractBefore(a.Source, "/");
            c = extractBefore(a.Destination, "/");
            for i = 1:size(a, 1)
                if ~isempty(b{i})
                    a.Source{i} = b{i};
                end
                if ~isempty(c{i})
                    a.Destination{i} = c{i};
                end
            end
            EndNodes = [a.Source a.Destination];
            nodeTable = table(EndNodes);
            graph = digraph(nodeTable);
        end

        function showModelPreview(app, modelOptions)
            % Shows the parameters of a selected model

            app.T_NoModelLoadedforPreviewLabel.Visible = "off";

            cla(app.T_PreviewUIAxes);

            app.T_PreviewGeneralInformationUITable.Data = [];
            app.T_PreviewHyperparametersUITable.Data = [];

            newData = [];

            newData = [newData; {"Model Name:", modelOptions.name}];
            newData = [newData; {"Type:", modelOptions.type}];
            newData = [newData; {"Learning Type:", modelOptions.learningType}];

            if isfield(modelOptions, "modelType")
                newData = [newData; {"Model Type:", modelOptions.modelType}];
            end

            if isfield(modelOptions, "isMultivariate")
                if modelOptions.isMultivariate
                    newData = [newData; {"Model Dimensionality:", "multivariate"}];
                else
                    newData = [newData; {"Model Dimensionality:", "univariate"}];
                end
            end

            if isfield(modelOptions, "useSubsequences")
                if modelOptions.useSubsequences
                    newData = [newData; {"Use Subsequences:", "yes"}];
                else
                    newData = [newData; {"Use Subsequences:", "no"}];
                end
            end

            app.T_PreviewGeneralInformationUITable.Data = table(newData);

            if isfield(modelOptions, "hyperparameters")
                hyperparametersData = [];
                paramNames = fieldnames(modelOptions.hyperparameters);
                for i = 1:numel(paramNames)
                    hyperparametersData = [hyperparametersData; {sprintf("%s:", paramNames{i}), string(modelOptions.hyperparameters.(paramNames{i}))}];
                end
                app.T_PreviewHyperparametersUITable.Data = table(hyperparametersData);
            end


            if strcmp(modelOptions.type, "deep-learning")
                graph = app.createModelGraphForPreview(modelOptions);
                plot(app.T_PreviewUIAxes, graph);
                app.T_PreviewUIAxes.Title.String = "Network Architecture";
            else
                app.T_PreviewUIAxes.Title.String = "";
            end

            app.T_PreviewGeneralInformationUITable.Visible = "on";
            app.T_PreviewHyperparametersUITable.Visible = "on";
            app.T_PreviewShowingLabel.Text = sprintf("Showing: %s", modelOptions.label);
            app.T_PreviewShowingLabel.Visible = "on";
        end

%% Detection panel functions
        
        function runDetection(app, models)
            % Main detection function
            % Loops over all models and runs the detection
            
            fprintf("Running detection ...\n");

            app.info_dimensionMismatchDetected = false;

            if isempty(models)
                uialert(app.TSADPlatformUIFigure, "No Models (selected).", ...
                        "Detection Error", Icon="error");
                return;
            end

            selectedTestFile = app.DE_TestDataFileDropDown.Value;
            [~, data_idx] = ismember(selectedTestFile, app.data_fileNamesTest);
            testFileVarName = genvarname(selectedTestFile);

            fprintf("Selected file: %s\n", selectedTestFile);

            numModels = numel(models);
            
            for model_idx = 1:numModels
                trainedModel = app.trainedModels.(models(model_idx).id);

                if isfield(trainedModel, "dimensionality")
                    if app.info_numChannels ~= trainedModel.dimensionality
                        uialert(app.TSADPlatformUIFigure, sprintf("%s dimensionality doesn't match the number of channels in the selected test data.", trainedModel.modelOptions.label), ...
                                "Dimensions Error", Icon="error");
                        app.info_dimensionMismatchDetected = true;
                        return;
                    end
                end

                fprintf("Detecting with: %s ...\n", trainedModel.modelOptions.label);

                testingData = app.data_dataTest(data_idx);
                testingLabels = app.data_labelsTest(data_idx);

                % Run detection
                [XTest, YTest, labels] = prepareDataTest(trainedModel.modelOptions, ...
                                                         testingData, testingLabels);
                
                [anomalyScores, compTime] = detectionWrapper(trainedModel, XTest, YTest, labels, ...
                                                             app.DE_GetComputationTimeCheckBox.Value);

                app.updateCompTime(compTime, models(model_idx).label, testFileVarName);
                
                if model_idx == 1
                    % For first model plot results 
                    app.updateDetection(trainedModel, anomalyScores, YTest, labels, ...
                                        testFileVarName, true, true);
                else
                    % Else just save scores
                    app.updateDetection(trainedModel, anomalyScores, YTest, labels, ...
                                        testFileVarName, false, true);
                end
            end
            
            app.updateDetectionPanel();
            fprintf("Finished detection successfully!\n");
        end

        function runDetectionForAllFiles(app, models)
            % Loops over all models and files and runs the detection
            
            fprintf("Running detection on all files ...\n");

            app.info_dimensionMismatchDetected = false;

            if isempty(models)
                uialert(app.TSADPlatformUIFigure, "No Models (selected).", ...
                        "Detection Error", Icon="error");
                return;
            end

            numTestFiles = numel(app.data_fileNamesTest);
            
            for data_idx = 1:numTestFiles
                currentFileName = app.data_fileNamesTest(data_idx);
                testFileVarName = genvarname(currentFileName);

                fprintf("Detecting on file: %s\n", currentFileName);
    
                numModels = numel(models);
                
                for model_idx = 1:numModels
                    trainedModel = app.trainedModels.(models(model_idx).id);
    
                    if isfield(trainedModel, "dimensionality")
                        if app.info_numChannels ~= trainedModel.dimensionality
                            uialert(app.TSADPlatformUIFigure, sprintf("%s dimensionality doesn't match the number of channels in the selected test data.", trainedModel.modelOptions.label), ...
                                    "Dimensions Error", Icon="error");
                            app.info_dimensionMismatchDetected = true;
                            return;
                        end
                    end
    
                    fprintf("Detecting with: %s ...\n", trainedModel.modelOptions.label);
    
                    testingData = app.data_dataTest(data_idx);
                    testingLabels = app.data_labelsTest(data_idx);
    
                    % Run detection
                    [XTest, YTest, labels] = prepareDataTest(trainedModel.modelOptions, ...
                                                             testingData, testingLabels);
                    
                    [anomalyScores, compTime] = detectionWrapper(trainedModel, XTest, YTest, labels, ...
                                                                 app.DE_GetComputationTimeCheckBox.Value);
    
                    app.updateCompTime(compTime, models(model_idx).label, testFileVarName);
                    
                    if model_idx == 1 && data_idx == 1
                        % For first model and file plot results  
                        app.updateDetection(trainedModel, anomalyScores, YTest, labels, ...
                                            testFileVarName, true, true);
                        app.DE_TestDataFileDropDown.Value = app.DE_TestDataFileDropDown.Items{data_idx};
                    else
                        % Else just save scores
                        app.updateDetection(trainedModel, anomalyScores, YTest, labels, ...
                                            testFileVarName, false, true);
                    end
                end
            end
            
            app.updateDetectionPanel();
            fprintf("Finished detection successfully!\n");
        end

        function updateAppliedThreshold(app, models)
            % Applys the selected threshold to the stored anomaly scores
            fprintf("Updating thresholds to %s ...\n", app.DE_ThresholdDropDown.Value);
            
            selectedFileName = app.DE_TestDataFileDropDown.Value;
            testFileVarName = genvarname(selectedFileName);

            fprintf("Selected file: %s\n", selectedFileName);
            
            if ~isfield(app.storedTestAnomalyScores, testFileVarName)
                return;
            end
            
            % Find models with stored anomaly scores
            updatableModels = [];
            for model_idx = 1:numel(models)
                if isfield(app.storedTestAnomalyScores, testFileVarName) && ...
                        isfield(app.storedTestAnomalyScores.(testFileVarName), models(model_idx).id)
                    updatableModels = [updatableModels, models(model_idx)];
                end
            end

            for model_idx = 1:numel(updatableModels)                
                trainedModel = app.trainedModels.(updatableModels(model_idx).id);
                anomalyScores = app.storedTestAnomalyScores.(testFileVarName).(updatableModels(model_idx).id).anomalyScores;
                YTest = app.storedTestAnomalyScores.(testFileVarName).(updatableModels(model_idx).id).YTest;
                labels = app.storedTestAnomalyScores.(testFileVarName).(updatableModels(model_idx).id).labels;

                if model_idx == 1
                    % Plot detection for first model
                    app.updateDetection(trainedModel, anomalyScores, YTest, labels, ...
                                        testFileVarName, true, false);
                else
                    app.updateDetection(trainedModel, anomalyScores, YTest, labels, ...
                                        testFileVarName, false, false);
                end
            end

            app.updateDetectionPanel();
            fprintf("Finished updating thresholds successfully!\n");
        end

        function updateAppliedThresholdForAllFiles(app, models)
            % Applys the selected threshold to the stored anomaly scores
            % for all test files
            fprintf("Updating thresholds for all files to %s ...\n", app.DE_ThresholdDropDown.Value);

            numTestFiles = numel(app.data_fileNamesTest);
            
            updatableFileNames = [];
            updatableFileVarNames = [];
            firstUpdatableFile_idx = [];
            
            for data_idx = 1:numTestFiles
                currentFileName = app.data_fileNamesTest(data_idx);
                testFileVarName = genvarname(currentFileName);
                if isfield(app.storedTestAnomalyScores, testFileVarName)
                    for model_idx = 1:numel(models)
                        if isfield(app.storedTestAnomalyScores.(testFileVarName), models(model_idx).id)
                            updatableFileNames = [updatableFileNames, currentFileName];
                            updatableFileVarNames = [updatableFileVarNames, string(testFileVarName)];
                            if isempty(firstUpdatableFile_idx)
                                firstUpdatableFile_idx = data_idx;
                            end
                            break;
                        end
                    end
                end
            end
            
            numUpdatableFiles = numel(updatableFileVarNames);
            for data_idx = 1:numUpdatableFiles
                testFileVarName = updatableFileVarNames(data_idx);
                fprintf("Updating thresholds for file: %s\n", updatableFileNames(data_idx));

                updatableModels = [];
                for model_idx = 1:numel(models)
                    if isfield(app.storedTestAnomalyScores.(testFileVarName), models(model_idx).id)
                        updatableModels = [updatableModels, models(model_idx)];
                    end
                end

                for model_idx = 1:numel(updatableModels)                
                    trainedModel = app.trainedModels.(updatableModels(model_idx).id);
                    anomalyScores = app.storedTestAnomalyScores.(testFileVarName).(updatableModels(model_idx).id).anomalyScores;
                    YTest = app.storedTestAnomalyScores.(testFileVarName).(updatableModels(model_idx).id).YTest;
                    labels = app.storedTestAnomalyScores.(testFileVarName).(updatableModels(model_idx).id).labels;
    
                    if data_idx == 1 && model_idx == 1
                        % Plot detection for first model and file
                        app.updateDetection(trainedModel, anomalyScores, YTest, labels, ...
                                            testFileVarName, true, false);
                        app.DE_TestDataFileDropDown.Value = app.DE_TestDataFileDropDown.Items{firstUpdatableFile_idx};
                    else
                        % Just update scores
                        app.updateDetection(trainedModel, anomalyScores, YTest, labels, ...
                                            testFileVarName, false, false);
                    end
                end
            end

            app.updateDetectionPanel();
            fprintf("Finished updating thresholds successfully!\n");
        end

        function updateDetection(app, trainedModel, anomalyScores, YTest, labels, testFileVarName, plotEnabled, isInitialDetection)
            % Updates the detection by applying the slected threshold and
            % updating the plots and saved scores...

            if ~trainedModel.modelOptions.outputsLabels
                selectedThresholdId = getThresholdId(app.DE_ThresholdDropDown.Value);

                % Check if threshold in storage to avoid potentially
                % re-calculating the same threshold
                if ~isInitialDetection && isfield(app.storedThresholdValues, testFileVarName) && ...
                        isfield(app.storedThresholdValues.(testFileVarName), trainedModel.modelOptions.id) && ...
                        isfield(app.storedThresholdValues.(testFileVarName).(trainedModel.modelOptions.id), selectedThresholdId)
                    storedThreshold = app.storedThresholdValues.(testFileVarName).(trainedModel.modelOptions.id).(selectedThresholdId);
                    storedThresholdFound = true;
                else
                    storedThreshold = [];
                    storedThresholdFound = false;
                end

                [predictedLabels, threshold] = applyThresholdToAnomalyScores(trainedModel, anomalyScores, ...
                                                                             labels, selectedThresholdId, ...
                                                                             app.settings_dynamicThreshold, storedThreshold);
            else
                predictedLabels = anomalyScores;
                threshold = [];
            end

            scores = calcScores(anomalyScores, predictedLabels, labels, trainedModel.modelOptions.outputsLabels);
            
            app.updateFullScores(scores, trainedModel.modelOptions.label, ...
                                        testFileVarName);

            if isInitialDetection
                % Save anomaly scores for new detection and reset stored
                % thresholds
                app.updateAnomalyScoreStorage(anomalyScores, YTest, labels, ...
                                              trainedModel.modelOptions.id, ...
                                              testFileVarName);
                if ~trainedModel.modelOptions.outputsLabels
                    app.removeStoredThresholdsOfModel(trainedModel.modelOptions.id, ...
                                                      testFileVarName);
    
                    app.updateThresholdStorage(selectedThresholdId, threshold, ...
                                               trainedModel.modelOptions.id, ...
                                               testFileVarName);
                end
            elseif ~trainedModel.modelOptions.outputsLabels && ~storedThresholdFound
                % Save threshold if not already stored
                app.updateThresholdStorage(selectedThresholdId, threshold, ...
                                           trainedModel.modelOptions.id, ...
                                           testFileVarName);
            end
            
            % Plot if enabled
            if plotEnabled
                app.plotPrediction(anomalyScores, ...
                                   predictedLabels, labels, YTest, ...
                                   threshold, trainedModel.modelOptions.outputsLabels, ...
                                   trainedModel.modelOptions.label, ...
                                   testFileVarName);

                app.currentDetection_anomalyScores = anomalyScores;
                app.currentDetection_YTest = YTest;
                app.currentDetection_labels = labels;
                app.currentDetection_trainedModel = trainedModel;
    
                currentModelLabel = trainedModel.modelOptions.label;
                app.DE_DetectionShownForLabel.Text = sprintf("Detection Shown for %s on file %s", currentModelLabel, testFileVarName);
            end
        end
        
        function plotPrediction(app, anomalyScores, predictedLabels, labels, dataY, threshold, justLabels, modelLabel, testFileVarName)
            % Plots the results of a prediction
            cla(app.DE_PlotsAnomalyScoresUIAxes);
            cla(app.DE_PlotsDataUIAxes);

            % Plot time series
            if iscell(dataY)
                dataY = cell2mat(dataY);
            end
            plot(app.DE_PlotsDataUIAxes, dataY(:, 1));

            % Plot anomaly scores / binary detection for models which
            % output labels
            if justLabels
                app.DE_PlotsAnomalyScoresUIAxes.Title.String  = "Predicted Labels";
                plot(app.DE_PlotsAnomalyScoresUIAxes, predictedLabels);
            else
                app.DE_PlotsAnomalyScoresUIAxes.Title.String  = "Anomaly Scores";
                plot(app.DE_PlotsAnomalyScoresUIAxes, anomalyScores);
            end
            
            % Plot transparent boxes for true and found anomalies
            predictedIndices = getIndexes(predictedLabels);
            trueIndices = getIndexes(labels);
            
            YLims = get(app.DE_PlotsDataUIAxes, "YLim");
            predictedLims = [];
            trueLims = [];
            for i = 1:size(predictedIndices, 2)
                predictedLims(1, i) = YLims(1);
                predictedLims(2, i) = YLims(1);
                predictedLims(3, i) = YLims(2);
                predictedLims(4, i) = YLims(2);
            end
            for i = 1:size(trueIndices, 2)
                trueLims(1, i) = YLims(1);
                trueLims(2, i) = YLims(1);
                trueLims(3, i) = YLims(2);
                trueLims(4, i) = YLims(2);
            end
            
            if ~isempty(predictedIndices) && ~isempty(predictedLims)
                patch(app.DE_PlotsDataUIAxes, predictedIndices, predictedLims, "red", EdgeColor="none", FaceAlpha=0.4);
            end
            if ~isempty(trueIndices) && ~isempty(trueLims)
                patch(app.DE_PlotsDataUIAxes, trueIndices, trueLims, "green", EdgeColor="none", FaceAlpha=0.3);
            end

            % Plot threshold(s)
            if ~justLabels
                if numel(threshold) > 1
                    windowSize = max(1, floor(size(anomalyScores, 1) * (app.settings_dynamicThreshold.windowSize / 100)));
                    threshX = [];
                    threshY = [];
                    for i = 1:numel(threshold)
                        threshX(i, :) = ((i - 1) * windowSize + 1):(windowSize * i);
                        threshY(i, :) = ones(1, windowSize) * threshold(i);
                    end

                    hold(app.DE_PlotsAnomalyScoresUIAxes, "on")
                    plot(app.DE_PlotsAnomalyScoresUIAxes, threshX', threshY', Color="red")
                    hold(app.DE_PlotsAnomalyScoresUIAxes, "off")
                else
                    yline(app.DE_PlotsAnomalyScoresUIAxes, threshold, "r");
                end
            end
            
            % Limits
            set(app.DE_PlotsAnomalyScoresUIAxes, "XLim", [0 size(anomalyScores, 1)]);
            set(app.DE_PlotsDataUIAxes, "XLim", [0 size(anomalyScores, 1)]);
%             set(app.DE_PlotsAnomalyScoresUIAxes, "YLim", [min(anomalyScores) max(anomalyScores)]);
%             set(app.DE_PlotsDataUIAxes, "YLim", [min(dataY(:, 1)) max(dataY(:, 1))]);
            
            % Legends
            try
                legend(app.DE_PlotsDataUIAxes, "Data", "Detected Anomalies", "True Anomalies")
            catch
                legend(app.DE_PlotsDataUIAxes, "Data", "Detected Anomalies")
            end            
            
            % Table
            scores = app.fullScores.(testFileVarName).(modelLabel);
            app.DE_PlotsScoresUITable.Data = table(METRIC_NAMES_WITH_COMP_TIME, scores);
        end

        function clearDetectionPlots(app)
            app.DE_DetectionShownForLabel.Text = "Detection Shown for: None";
            app.DE_PlotsScoresUITable.Data = [];
            cla(app.DE_PlotsDataUIAxes);
            cla(app.DE_PlotsAnomalyScoresUIAxes);
            app.currentDetection_anomalyScores = [];
            app.currentDetection_labels = [];
            app.currentDetection_YTest = [];
            app.currentDetection_trainedModel = [];
            app.info_detectionRun = false;
        end
        
        function updateTinyDataPreview(app)
            selectedDataFile = app.DE_TestDataFileDropDown.Value;
            [~, data_idx] = ismember(selectedDataFile, app.DE_TestDataFileDropDown.Items);
            plot(app.DE_TinyDataPreviewUIAxes, app.data_dataTest{data_idx});
        end
        
        function initScores(app)
            % Initializes all computed scores for every file of the testing
            % dataset and the average scores
            
            % Remove old scores
            oldFiles = fieldnames(app.fullScores);
            currentFiles = genvarname(app.data_fileNamesTest);
            for data_idx = 1:numel(oldFiles)
                if ~ismember(oldFiles{data_idx}, currentFiles)
                    app.fullScores = rmfield(app.fullScores, oldFiles{data_idx});
                end
            end

            table_tmp = table(METRIC_NAMES_WITH_COMP_TIME);

            for data_idx = 1:numel(app.data_fileNamesTest)
                testFileVarName = genvarname(app.data_fileNamesTest(data_idx));
                if ~isfield(app.fullScores, testFileVarName)
                    app.fullScores.(testFileVarName) = table_tmp;
                    app.fullScores.(testFileVarName).Properties.VariableNames = "Metric";
                end
            end
            
            trainedModelIds = fieldnames(app.trainedModels);
            if ~isempty(trainedModelIds)
                for data_idx = 1:numel(trainedModelIds)
                    app.addModelToScores(app.trainedModels.(trainedModelIds{data_idx}).modelOptions.label);
                end
            end
        end

        function updateCompTimePlot(app)
            % Updates computation time plot

            cla(app.DE_CompTimeUIAxes);
            if isempty(app.compTimeMetrics)
                xticks(app.DE_CompTimeUIAxes, []);
                return;
            end

            shownScores = app.fullScores.(genvarname(app.DE_TestDataFileDropDown.Value));

            metrics_idx = [];
            for i = 1:numel(app.compTimeMetrics)                
                metrics_idx(i) = find(shownScores{:, 1} == string(app.compTimeMetrics(i)));
            end

            elapsed = shownScores{1, 2:end};
            elapsed_idx = 2:(size(shownScores, 2));
            nan_idx = isnan(elapsed);
            elapsed_idx(nan_idx) = [];
            elapsed(nan_idx) = [];

            [elapsed, sort_idx] = sort(elapsed);
            elapsed_idx = elapsed_idx(sort_idx);

            if numel(elapsed) > 1
                for i = 1:numel(elapsed) - 1
                    if elapsed(i + 1) == elapsed(i)
                        elapsed(i + 1) = elapsed(i + 1) + 0.0001;
                    end
                end
            end

            cla(app.DE_CompTimeUIAxes)

            if ~isempty(app.compTimeMetrics)
                hold(app.DE_CompTimeUIAxes, "on");
                for i = 1:numel(app.compTimeMetrics)
                    plot(app.DE_CompTimeUIAxes, elapsed, shownScores{metrics_idx(i), elapsed_idx}, ".", MarkerSize=5);
                end
                legend(app.DE_CompTimeUIAxes, app.compTimeMetrics, Location="northwest");                
                xticks(app.DE_CompTimeUIAxes, elapsed);
                xticklabels(app.DE_CompTimeUIAxes, shownScores.Properties.VariableNames(elapsed_idx));
            end
        end
        
        function updateAnomalyScoreStorage(app, anomalyScores, YTest, labels, modelId, testFileVarName)
            % Stores already computed anomaly scores

            app.storedTestAnomalyScores.(testFileVarName).(modelId).YTest = YTest;
            app.storedTestAnomalyScores.(testFileVarName).(modelId).anomalyScores = anomalyScores;
            app.storedTestAnomalyScores.(testFileVarName).(modelId).labels = labels;
        end

        function updateThresholdStorage(app, thresholdId, thresholdValue, modelId, testFileVarName)
            % Stores already computed thresholds
            
            if ~strcmp(thresholdId, "dynamic") % Don't save dynamic threshold because it can be reconfigured.
                app.storedThresholdValues.(testFileVarName).(modelId).(thresholdId) = thresholdValue;
            end
        end

        function updateFullScores(app, scores, modelLabel, testFileVarName)
            % updates the scores saved in the full scores

            compTime = app.fullScores.(testFileVarName).(modelLabel)(1);
            app.fullScores.(testFileVarName).(modelLabel) = [compTime; scores];
        end

        function updateAverageScores(app)
            % updates average scores saved in averageScores table

            testedFileNames = fieldnames(app.fullScores);
            
            if isempty(testedFileNames)
                return;
            end

            fullScoresCell = cell(numel(testedFileNames), 1);
            for i = 1:numel(testedFileNames)
                fullScoresCell{i} = table2array(app.fullScores.(testedFileNames{i})(:, 2:end));
            end

            app.averageScores = app.fullScores.(testedFileNames{1}); % Copy full scores to average scores to init layout of table
            app.averageScores{:, 2:end} = calcAverageScores(fullScoresCell); % Now fill with average scores
        end

        function updateCompTime(app, compTime, modelLabel, testFileVarName)
            % Updates the computation time in the full scores

            scores = app.fullScores.(testFileVarName).(modelLabel)(2:end);
            app.fullScores.(testFileVarName).(modelLabel) = [compTime; scores];
        end

        function updateDetectionPanel(app)
            % Updates the detection panel

            if ~app.info_testDataLoaded
                return;
            end
            
            app.updateAverageScores();
            app.showScoresInTable();
            app.showTrainedModels();
            app.sortTrainedModels();
            app.updateCompTimePlot();
        end
                
        function resetDetectionStorage(app)
            % Removes all stored scores, anomaly scores and thresholds
            app.fullScores = struct();
            app.averageScores = table();
            app.storedTestAnomalyScores = struct();
            app.storedThresholdValues = struct();
        end

        function addModelToScores(app, modelLabel)
            % Adds a new model to the full scores struct
            
            testFileNames = fieldnames(app.fullScores);
            if ~isempty(testFileNames)             
                for i = 1:numel(testFileNames)
                    if ~ismember(modelLabel, app.fullScores.(testFileNames{i}).Properties.VariableNames)
                        app.fullScores.(testFileNames{i}).(modelLabel) = [NaN; NaN; NaN; NaN; ...
                                                                          NaN; NaN; NaN; NaN; ...
                                                                          NaN; NaN; NaN; NaN; ...
                                                                          NaN; NaN; NaN; NaN];
                    end
                end
            end
        end

        function removeModelFromScores(app, modelLabel)
            % Removes a model from the full scores
            
            testFileNames = fieldnames(app.fullScores);
            if ~isempty(testFileNames)                
                for i = 1:numel(testFileNames)
                    if ismember(modelLabel, app.fullScores.(testFileNames{i}).Properties.VariableNames)
                        app.fullScores.(testFileNames{i}) = removevars(app.fullScores.(testFileNames{i}), modelLabel);
                    end
                end
            end
        end

        function removeStoredThresholdsOfModel(app, modelId, testFileVarName)
            % Removes stored thresholds for model and data file

            if isfield(app.storedThresholdValues, testFileVarName) && ...
                    isfield(app.storedThresholdValues.(testFileVarName), modelId)
                storedThresholds = fieldnames(app.storedThresholdValues.(testFileVarName).(modelId));
                for i = 1:numel(storedThresholds)
                    app.storedThresholdValues.(testFileVarName).(modelId) = rmfield(app.storedThresholdValues.(testFileVarName).(modelId), storedThresholds{i});
                end
            end
        end

        function removeModelFromDetectionStorage(app, modelId)
            % Removes a model from the stored anomaly scores and thresholds

            testFileNames = fieldnames(app.storedTestAnomalyScores);
            if ~isempty(testFileNames)                
                for i = 1:numel(testFileNames)
                    if isfield(app.storedTestAnomalyScores.(testFileNames{i}), modelId)
                        app.storedTestAnomalyScores.(testFileNames{i}) = rmfield(app.storedTestAnomalyScores.(testFileNames{i}), modelId);
                    end
                end
            end
            
            testFileNames = fieldnames(app.storedThresholdValues);
            if ~isempty(testFileNames)
                for i = 1:numel(testFileNames)
                    if isfield(app.storedThresholdValues.(testFileNames{i}), modelId)
                        app.storedThresholdValues.(testFileNames{i}) = rmfield(app.storedThresholdValues.(testFileNames{i}), modelId);
                    end
                end
            end
        end
        
        function removePreviousDetectionsOfModel(app, modelId, modelLabel)
            % Removes all previous detections of a model if it was trained
            % again
            
            app.removeModelFromScores(modelLabel);
            app.removeModelFromDetectionStorage(modelId);

            if ~isempty(app.currentDetection_trainedModel)
                if strcmp(app.currentDetection_trainedModel.modelOptions.id, modelId)
                    app.clearDetectionPlots();
                end
            end
        end

        function removeTrainedModelsAndDetections(app, models)
            % Removes the detection for a all deleted models

            for i = 1:numel(models)
                modelId = models(i).modelOptions.id;
                modelLabel = models(i).modelOptions.label;
                if isfield(app.trainedModels, modelId)
                    app.trainedModels = rmfield(app.trainedModels, modelId);
                end

                app.removePreviousDetectionsOfModel(modelId, modelLabel);
            end
        end

        function showTrainedModels(app)
            % Shows the trained models on the detection panels

            app.DE_TrainedModelsListBox.Items = {};
            app.DE_TrainedModelsListBox.ItemsData = [];
            app.SD_TrainedModelsListBox.Items = {};
            app.SD_TrainedModelsListBox.ItemsData = [];

            if isempty(fieldnames(app.trainedModels))
                % If no models are loaded or trained
                return;
            end
            
            trainedModelIds = fieldnames(app.trainedModels);
            if ~isempty(trainedModelIds)                
                for i = 1:numel(trainedModelIds)
                    modelLabel = app.trainedModels.(trainedModelIds{i}).modelOptions.label;

                    modelInfo.label = modelLabel;
                    modelInfo.id = trainedModelIds{i};

                    app.DE_TrainedModelsListBox.Items = [app.DE_TrainedModelsListBox.Items ...
                        modelLabel];
                    app.DE_TrainedModelsListBox.ItemsData = [app.DE_TrainedModelsListBox.ItemsData ...
                        modelInfo];

                    if strcmp(app.trainedModels.(trainedModelIds{i}).modelOptions.type, "deep-learning")
                        app.SD_TrainedModelsListBox.Items = [app.SD_TrainedModelsListBox.Items ...
                            modelLabel];
                        app.SD_TrainedModelsListBox.ItemsData = [app.SD_TrainedModelsListBox.ItemsData ...
                            modelInfo];
                    end
                end
            end
        end

        function showScoresInTable(app)
            % Shows scores for current data file and trained models

            shownScores = app.fullScores.(genvarname(app.DE_TestDataFileDropDown.Value));
            app.DE_ScoresUITable.Data = {};
            app.DE_AverageScoresUITable.Data = {};
            if ~isempty(shownScores)
                app.DE_ScoresUITable.Data = shownScores;
                app.DE_AverageScoresUITable.Data = app.averageScores;
                app.DE_ScoresUITable.ColumnName = shownScores.Properties.VariableNames;
                app.DE_AverageScoresUITable.ColumnName = app.averageScores.Properties.VariableNames;
                
                if size(shownScores, 2) > 1
                    removeStyle(app.DE_ScoresUITable);
                    removeStyle(app.DE_AverageScoresUITable);

                    % Add blue background for comp time row
                    styleBlue = uistyle;
                    styleBlue.BackgroundColor = "#97b0e7";
                    addStyle(app.DE_ScoresUITable, styleBlue, row=1);
                    addStyle(app.DE_AverageScoresUITable, styleBlue, row=1);

                    % Highlight maxima
                    app.highlightTableMaxima(app.DE_ScoresUITable, "all", 1);
                    app.highlightTableMaxima(app.DE_AverageScoresUITable, "all", 1);
                end
            end
        end

        function sortTrainedModels(app)
            % Sorts trained models according to their scores specified by
            % the sort-by dropdown

            sortBy = app.DE_SortByDropDown.Value;
            [~, row_idx] = ismember(sortBy, app.averageScores{:, 1});

            numModels = numel(app.DE_TrainedModelsListBox.ItemsData);
            values = zeros(1, numModels);
            for i = 1:numModels
                fieldName = app.DE_TrainedModelsListBox.ItemsData(i).label;
                modelScores = app.averageScores.(fieldName);

                values(i) = modelScores(row_idx);
                if isnan(values(i))
                    values(i) = 0;
                end
            end

            [~, idxs] = sort(values, "descend");

            sortedItems = app.DE_TrainedModelsListBox.Items(idxs);
            sortedItemsData = app.DE_TrainedModelsListBox.ItemsData(idxs);
            
            % Add model rank to label
            for i = 1:numel(sortedItems)
                sortedItems{i} = sprintf('%d. %s', i, sortedItemsData(i).label);
            end

            app.DE_TrainedModelsListBox.Items = sortedItems;
            app.DE_TrainedModelsListBox.ItemsData = sortedItemsData;
        end
     
        function path = saveModelForSim(app, Mdl, modelId)
            % Saves the selected model for the simulink detection

            f = figure("Renderer", "painters", "Position", [-100 -100 0 0]); % Dummy figure for keeping app in foreground after uigetfile/uiputfile
            [file, path] = uiputfile("*.mat", "Save Model for Simulink Detection", sprintf("%s.mat", modelId));
            delete(f);
            if isequal(path, 0)
                uialert(app.TSADPlatformUIFigure, "No valid path selected.", ...
                        "Export Error", Icon="error");
                return;
            end
                        
            fileName = fullfile(path, file);
            assignin("base", "Mdl", Mdl);
            save(fileName, "Mdl");
                                
            path = fileName;
        end

        function saveScoresTable(app, UITable, dlgTitle, fileName)
            table = UITable.Data;
            table.Properties.VariableNames = UITable.ColumnName;

            variableNames = table.Properties.VariableNames;
            if numel(variableNames) < 2
                return;
            end


            f = figure("Renderer", "painters", "Position", [-100 -100 0 0]); % Dummy figure for keeping app in foreground after uigetfile/uiputfile
            [file, path] = uiputfile("*.csv", dlgTitle, sprintf("%s_%s.csv", fileName, datestr(now,"mm-dd-yyyy_HH-MM")));
            delete(f);
            if isequal(path, 0)
                uialert(app.TSADPlatformUIFigure, "No valid path selected.", ...
                        "Export Error", Icon="error");
                return;
            end

            exportFileName = fullfile(path, file);
            
            try
                writetable(table, exportFileName);
            catch ME
                uialert(app.TSADPlatformUIFigure, ME.message, "Export Error", Icon="error");
                return;
            end

            uialert(app.TSADPlatformUIFigure, sprintf("Saved table to %s", exportFileName), ...
                    "Table Saved Successfully", Icon="success");
        end

        function exportTrainedModels(app)
            if isempty(fieldnames(app.trainedModels))
                uialert(app.TSADPlatformUIFigure, "No models found for export! Train them first.", ...
                        "Export Error", Icon="error");
                return;
            end

            f = figure("Renderer", "painters", "Position", [-100 -100 0 0]); % Dummy figure for keeping app in foreground after uigetfile/uiputfile
            [file, path] = uiputfile("*.mat", "Export trained Models", sprintf("models_%s.mat", datestr(now,"mm-dd-yyyy_HH-MM")));
            delete(f);
            if isequal(path, 0)
                uialert(app.TSADPlatformUIFigure, "No valid path selected.", ...
                        "Export Error", Icon="error");
                return;
            end

            d = uiprogressdlg(app.TSADPlatformUIFigure, Title="Exporting trained Models ...", ...
                              Indeterminate="on");
            drawnow;
            
            trainedModels = app.trainedModels;
            
            exportFileName = fullfile(path, file);
            assignin("base", "trainedModels", trainedModels);
            save(exportFileName, "trainedModels");
            
            close(d);
            uialert(app.TSADPlatformUIFigure, sprintf("Saved models to %s", exportFileName), ...
                    "Models Saved Successfully", Icon="success");
        end

    end

    methods (Access = public)
%% Settings update functions called by settings popup apps
        
        function updateThresholds(app, newThresholds)
            % Updates the selected thresholds settings

            app.settings_thresholds = newThresholds;
            items = getThresholdLabels(newThresholds);
            app.DE_ThresholdDropDown.Items = items;

            app.toggleDynamicThresholdSliders(app.DE_ThresholdDropDown.Value);

            [flag, idx] = ismember("Dynamic", items);
            if flag
                items(idx) = [];
            end
            app.SD_ThresholdDropDown.Items = items;
        end

        function updateDynamicThresholdSettings(app, newOptions)
            % Updates the dynamic threshold settings
            
            app.settings_dynamicThreshold = newOptions;

            app.DE_WindowSizeSlider.Value = app.settings_dynamicThreshold.windowSize;
            app.DE_AnomalyPaddingSlider.Value = app.settings_dynamicThreshold.anomalyPadding;
            app.DE_ZRangeSlider.Value = app.settings_dynamicThreshold.zRange;
        end
        
        function bestModels = findBestModels(app, metric)
            [~, metric_idx] = ismember(metric, METRIC_NAMES);
            metric_idx = metric_idx + 1; % Add 1 because fullScores containes the comp time in the first row

            testFiles = fieldnames(app.fullScores);

            for data_idx = 1:numel(testFiles)
                fileScores = app.fullScores.(testFiles{data_idx});
                [~, model_idx] = max(fileScores{metric_idx, 2:end});
                modelLabel = fileScores.Properties.VariableNames(model_idx + 1);
                for i = 1:numel(app.DE_TrainedModelsListBox.ItemsData)
                    if strcmp(app.DE_TrainedModelsListBox.ItemsData(i).label, modelLabel)
                        modelId = app.DE_TrainedModelsListBox.ItemsData(i).id;
                        break;
                    end
                end
                bestModels.(testFiles{data_idx}).id = modelId;
                bestModels.(testFiles{data_idx}).label = modelLabel;
            end
        end

%% Training panel functions called by popup apps
        
        function addModelToList(app, modelOptions)
            % Adds model to listboxes on training panel

            [modelLabel, modelId, instanceNumber] = app.getLabelForModel(modelOptions.name);
            modelOptions.id = modelId;
            modelOptions.label = modelLabel;
            modelOptions.instanceNumber = instanceNumber;
            modelInfo.modelOptions = modelOptions;
            
            app.T_ModelsListBox.Items = [app.T_ModelsListBox.Items modelLabel];
            app.T_ModelsListBox.ItemsData = [app.T_ModelsListBox.ItemsData modelInfo];
        end
        
        function runOptimizationFor(app, iterations, metric, threshold, selection, trainingPlots)
            % Runs the optimization for a specified selection
            
            % Delete pupup window
            app.popup_Optimization.delete();

            if ~app.info_testDataLoaded
                uialert(app.TSADPlatformUIFigure, "No testing data loaded!", ...
                        "Optimization Error", Icon="error");
                return;
            end

            switch selection
                case "selection"
                    models = app.T_ModelsListBox.Value;
                case "all"
                    % Load all models as added on the Training tab
                    models = app.T_ModelsListBox.ItemsData;
            end

            if isempty(models)
                uialert(app.TSADPlatformUIFigure, "No models found for optimization.", ...
                        "Model Error", Icon="error");
                return;
            end

            % If no training data is loaded but a model requiring prior
            % training was selected
            if isempty(app.data_dataTrain)
                for i = 1:numel(models)
                    if ~strcmp(models(i).modelOptions.learningType, "unsupervised")
                        uialert(app.TSADPlatformUIFigure, "You are trying to optimize a model but the selected dataset doesn't contain training data", ...
                                "Optimization Error", Icon="error");
                        return;
                    end
                end
            end
            
            d = uiprogressdlg(app.TSADPlatformUIFigure, Title="Running Auto Optimization ...", ...
                              Indeterminate="on");
            drawnow;

            app.runOptimizations(models, iterations, metric, ...
                                 threshold, trainingPlots);

            close(d);
            uialert(app.TSADPlatformUIFigure, "Successfully finished optimization.", ...
                    "Optimization Successful", Icon="success");
        end

        function updateScoringFunctionForSelection(app, scoringFunction)
            % Updates the scoring function for the selected models (only if
            % they have the scoringFuncion hyperparameter)
            
            % Delete pupup window
            app.popup_ScoringFunctionSelection.delete();

            models = app.T_ModelsListBox.Value;
   
            if isempty(models)
                uialert(app.TSADPlatformUIFigure, "No models selected.", ...
                        "Model Error", Icon="error");
                return;
            end


            d = uiprogressdlg(app.TSADPlatformUIFigure, Title="Updating Scoring Functions ...", ...
                              Indeterminate="on");
            drawnow;

            for model_idx = 1:numel(models)
                modelOptions_tmp = models(model_idx).modelOptions;
               
                % Convert modelOptions on training panel
                if isfield(modelOptions_tmp, "hyperparameters") && ...
                    isfield(modelOptions_tmp.hyperparameters, "scoringFunction")
                    modelOptions_tmp.hyperparameters.scoringFunction = scoringFunction;
                    app.convertModelOptionsInList(modelOptions_tmp);


                    % Update trained model
                    if isfield(app.trainedModels, modelOptions_tmp.id)
                        trainedModel_tmp = app.trainedModels.(modelOptions_tmp.id);
                        trainedModel_tmp.modelOptions = modelOptions_tmp;
                        updatedTrainedModels.(modelOptions_tmp.id) = trainedModel_tmp;
                    end
                end
            end

            if exist("updatedTrainedModels", "var")
                app.initNewTrainedModels(updatedTrainedModels);
                app.updateDetectionPanel();
            end

            close(d);
            uialert(app.TSADPlatformUIFigure, "Successfully updated scoring functions.", ...
                    "Update Successful", Icon="success");
        end
        
%% Detection panel functions calles by popup apps
        
        function updateCompTimeMetrics(app, newMetrics)
            % Updates the computation time metric selection

            app.compTimeMetrics = newMetrics;

            if app.info_detectionRun
                app.updateCompTimePlot();
            end
        end
    
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Turn off axes for model preview
            axis(app.T_PreviewUIAxes, "off")
            axis(app.DE_TinyDataPreviewUIAxes, "off");
            
            % Init metric drop downs
            app.DE_SortByDropDown.Items = METRIC_NAMES;
            app.DS_MetricDropDown.Items = METRIC_NAMES;
            
            % Init threshold dropdown items
            items = getThresholdLabels(app.settings_thresholds);
            app.DE_ThresholdDropDown.Items = items;
            [flag, idx] = ismember("Dynamic", items);
            if flag
                items(idx) = [];
            end
            app.SD_ThresholdDropDown.Items = items;
            
            % Init dynamic threshold settings
            app.settings_dynamicThreshold.windowSize = 50;
            app.settings_dynamicThreshold.anomalyPadding = 30;
            app.settings_dynamicThreshold.zRange = 5;
            app.settings_dynamicThreshold.minPercent = 1;

            app.DE_WindowSizeSlider.Value = app.settings_dynamicThreshold.windowSize;
            app.DE_AnomalyPaddingSlider.Value = app.settings_dynamicThreshold.anomalyPadding;
            app.DE_ZRangeSlider.Value = app.settings_dynamicThreshold.zRange;
        end

        % Close request function: TSADPlatformUIFigure
        function TSADPlatformUIFigureCloseRequest(app, event)
            answer = uiconfirm(app.TSADPlatformUIFigure, "Close the tsad platform?", "Confirm Close", ...
                Options={'Ok', 'Cancel'}, DefaultOption='Cancel', CancelOption='Cancel');

            if ~strcmp(answer, 'Ok')
                return;
            end
            
            if ~isempty(app.popup_ModelSelection)
                app.popup_ModelSelection.delete()
            end
            if ~isempty(app.popup_Optimization)
                app.popup_Optimization.delete()
            end
            if ~isempty(app.popup_OptimizationSettings)
                app.popup_OptimizationSettings.delete()
            end
            if ~isempty(app.popup_ScoringFunctionSelection)
                app.popup_ScoringFunctionSelection.delete()
            end
            if ~isempty(app.popup_ThresholdSelection)
                app.popup_ThresholdSelection.delete()
            end
            if ~isempty(app.popup_DynamicThresholdSettings)
                app.popup_DynamicThresholdSettings.delete()
            end
            if ~isempty(app.popup_CompTimeMetricSelection)
                app.popup_CompTimeMetricSelection.delete()
            end
            if ~isempty(app.popup_DatasetVisualization)
                app.popup_DatasetVisualization.delete()
            end
            if ~isempty(gcp("nocreate"))
                delete(gcp("nocreate"));
            end
            
            app.delete()
        end

        % Menu selected function: ThresholdSelectionMenu
        function ThresholdSelectionMenuSelected(app, event)
            app.popup_ThresholdSelection = ThresholdSelection(app, app.settings_thresholds);
        end

        % Menu selected function: DynamicThresholdMenu
        function DynamicThresholdMenuSelected(app, event)
            app.popup_DynamicThresholdSettings = DynamicThresholdSettings(app, app.settings_dynamicThreshold);
        end

        % Menu selected function: EnableParallelModeMenu
        function EnableParallelModeMenuSelected(app, event)
            if strcmp(app.EnableParallelModeMenu.Text, "Enable Parallel Mode")
                app.EnableParallelModeMenu.Text = "Disable Parallel Mode";
                app.settings_parallelEnabled = true;
            else
                app.EnableParallelModeMenu.Text = "Enable Parallel Mode";
                app.settings_parallelEnabled = false;
            end

            if app.settings_parallelEnabled
                uialert(app.TSADPlatformUIFigure, "Enabled parallel mode. Training will now be run in parallel.", ...
                        "Switched Mode Successfully", Icon="success");
            else
                uialert(app.TSADPlatformUIFigure, "Disabled parallel mode. Training will now be run consecutively.", ...
                        "Switched Mode Successfully", Icon="success");
            end
        end

        % Button pushed function: D_BrowseDatasetButton
        function D_BrowseDatasetButtonPushed(app, event)
            f = figure("Renderer", "painters", "Position", [-100 -100 0 0]); % Dummy figure for keeping app in foreground after uigetfile/uiputfile
            path = uigetdir(pwd);
            delete(f);
            if ~isequal(path, 0)
                app.D_DatasetFolderEditField.Value = path;
            end
        end

        % Button pushed function: D_LoadDataButton
        function D_LoadDataButtonPushed(app, event)
            datasetPath = app.D_DatasetFolderEditField.Value;
            
            if isempty(datasetPath)
                uialert(app.TSADPlatformUIFigure, "No valid dataset folder selected!", ...
                        "Dataset Error", Icon="error");
                return;
            end

            if ~isfolder(datasetPath)
                datasetPath = fullfile(pwd, datasetPath);
                if ~isfolder(datasetPath)
                    uialert(app.TSADPlatformUIFigure, "No valid dataset folder selected!", ...
                            "Dataset Error", Icon="error");
                    return;
                end
            end
            
            d = uiprogressdlg(app.TSADPlatformUIFigure, Title="Loading Dataset ...", ...
                              Indeterminate="on");
            drawnow;

            try
                [app.data_dataTrainRaw, ...
                 app.data_timestampsTrain, ...
                 app.data_labelsTrain, ...
                 app.data_fileNamesTrain, ...
                 app.data_dataTestRaw, ...
                 app.data_allTestTimestamps, ...
                 app.data_allTestLabels, ...
                 app.data_allTestFileNames, ...
                 app.data_channelNames] = loadCustomDataset(datasetPath);
            catch ME
                close(d);
                uialert(app.TSADPlatformUIFigure, ME.message, ...
                        "Dataset Error", Icon="error");
                return;
            end
            
            % Reset data related elements
            app.clearDatasetPlots();
            app.clearDynamicSwitchData();
            app.resetDetectionStorage();
            app.clearDetectionPlots();

            app.info_numTrainFiles = numel(app.data_fileNamesTrain);
            app.info_numTestFiles = numel(app.data_allTestFileNames);
            
            % Enable / disable dynamic switch depending of number of
            % testing files
            if app.info_numTestFiles < 2
                app.D_SplitTestSetForDynamicSwitchCheckBox.Enable = "off";
                app.D_RatioTestSwitchEditField.Enable = "off";
                app.D_RatioTestSwitchLabel.Enable = "off";
                app.D_SplitTestSetForDynamicSwitchCheckBox.Value = false;
                app.toggleDynamicSwitchPanel(false);
            else
                app.D_SplitTestSetForDynamicSwitchCheckBox.Enable = "on";
                if app.D_SplitTestSetForDynamicSwitchCheckBox.Value
                    app.D_RatioTestSwitchEditField.Enable = "on";
                    app.D_RatioTestSwitchLabel.Enable = "on";
                    app.toggleDynamicSwitchPanel(true);
                else
                    app.D_RatioTestSwitchEditField.Enable = "off";
                    app.D_RatioTestSwitchLabel.Enable = "off";
                    app.toggleDynamicSwitchPanel(false);
                end
            end

            if ~isempty(app.data_dataTrainRaw)
                app.info_numChannels = size(app.data_dataTrainRaw{1}, 2);

                app.D_SelectedFileTrainDropDown.Items = app.data_fileNamesTrain;
                
                app.info_dataLoaded = true;
                app.info_trainDataLoaded = true;
            else
                app.info_trainDataLoaded = false;
            end

            if ~isempty(app.data_dataTestRaw)
                app.info_numChannels = size(app.data_dataTestRaw{1}, 2);

                app.D_SelectedFileTestDropDown.Items = app.data_allTestFileNames;

                app.info_dataLoaded = true;
                app.info_testDataLoaded = true;
            else
                app.info_testDataLoaded = false;
            end
                        
            app.showDatasetParameters();
            app.runPreprocessing(true);
            app.updateDetectionPanel();

            close(d);
        end

        % Value changed function: D_PreprocessingMethodDropDown
        function D_PreprocessingMethodDropDownValueChanged(app, event)
            if ~app.info_dataLoaded
                return;
            end

            d = uiprogressdlg(app.TSADPlatformUIFigure, Title="Preprocessing Dataset ...", ...
                              Indeterminate="on");
            drawnow;
            
            app.runPreprocessing(true);
            
            close(d);
        end

        % Value changed function: D_SplitTestSetForDynamicSwitchCheckBox
        function D_SplitTestSetForDynamicSwitchCheckBoxValueChanged(app, event)
            value = app.D_SplitTestSetForDynamicSwitchCheckBox.Value;
            app.toggleDynamicSwitchPanel(value);
            
            if value
                app.D_RatioTestSwitchLabel.Enable = "on";
                app.D_RatioTestSwitchEditField.Enable = "on";
            else
                app.D_RatioTestSwitchLabel.Enable = "off";
                app.D_RatioTestSwitchEditField.Enable = "off";
            end

            if ~app.info_testDataLoaded
                return;
            end

            d = uiprogressdlg(app.TSADPlatformUIFigure, Title="Preprocessing Dataset ...", ...
                              Indeterminate="on");
            drawnow;
            
            app.runPreprocessing(false);
            
            close(d);
        end

        % Value changed function: D_RatioTestSwitchEditField
        function D_RatioTestSwitchEditFieldValueChanged(app, event)
            if ~app.info_testDataLoaded
                return;
            end
            
            d = uiprogressdlg(app.TSADPlatformUIFigure, Title="Preprocessing Dataset ...", ...
                              Indeterminate="on");
            drawnow;
            
            app.runPreprocessing(false);
            
            close(d);
        end

        % Value changed function: D_UseAnomalousValidationSetCheckBox
        function D_UseAnomalousValidationSetCheckBoxValueChanged(app, event)
            if app.D_UseAnomalousValidationSetCheckBox.Value
                app.D_RatioTestValLabel.Enable = "on";
                app.D_RatioTestValEditField.Enable = "on";
            else
                app.D_RatioTestValLabel.Enable = "off";
                app.D_RatioTestValEditField.Enable = "off";
            end

            if ~app.info_testDataLoaded
                return;
            end

            d = uiprogressdlg(app.TSADPlatformUIFigure, Title="Preprocessing Dataset ...", ...
                              Indeterminate="on");
            drawnow;

            app.runPreprocessing(false);
            
            close(d);
        end

        % Value changed function: D_RatioTestValEditField
        function D_RatioTestValEditFieldValueChanged(app, event)
            if ~app.info_testDataLoaded
                return;
            end
            
            d = uiprogressdlg(app.TSADPlatformUIFigure, Title="Preprocessing Dataset ...", ...
                              Indeterminate="on");
            drawnow;
            
            app.runPreprocessing(false);
            
            close(d);
        end

        % Value changed function: D_SelectedFileTrainDropDown
        function D_SelectedFileTrainDropDownValueChanged2(app, event)
            app.updatePreviewData("train");
        end

        % Value changed function: D_SelectedChannelTrainDropDown
        function D_SelectedChannelTrainDropDownValueChanged2(app, event)
            app.updatePreviewData("train");
        end

        % Value changed function: D_SelectedFileTestDropDown
        function D_SelectedFileTestDropDownValueChanged2(app, event)
            app.updatePreviewData("test");
        end

        % Value changed function: D_SelectedChannelTestDropDown
        function D_SelectedChannelTestDropDownValueChanged2(app, event)
            app.updatePreviewData("test");
        end

        % Value changed function: D_SelectedFileTestValDropDown
        function D_SelectedFileTestValDropDownValueChanged(app, event)
            app.updatePreviewData("test-val");
        end

        % Value changed function: D_SelectedChannelTestValDropDown
        function D_SelectedChannelTestValDropDownValueChanged(app, event)
            app.updatePreviewData("test-val");
        end

        % Value changed function: D_AugmentationIntensitySlider
        function D_AugmentationIntensitySliderValueChanged(app, event)
            if ~app.info_dataLoaded
                return;
            end

            d = uiprogressdlg(app.TSADPlatformUIFigure, Title="Preprocessing Dataset ...", ...
                              Indeterminate="on");
            drawnow;

            app.runPreprocessing(true);

            close(d);            
        end

        % Value changed function: D_AugmentationModeDropDown
        function D_AugmentationModeDropDownValueChanged(app, event)
            value = app.D_AugmentationModeDropDown.Value;

            %changing the visual of the slider depending on the transformation chosen

            switch value
                case "none"
                    app.D_AugmentationIntensitySlider.Visible="off";
                    app.D_AugmentationIntensityLabel.Visible="off";
                    app.D_AugmentedTrainingCheckBock.Visible = "off";
                case "white noise"
                    app.D_AugmentationIntensitySlider.Limits = [0, 1];
                    app.D_AugmentationIntensityLabel.Text = "Standard deviation";
                    app.D_AugmentationIntensitySlider.Visible="on";
                    app.D_AugmentationIntensityLabel.Visible="on";
                    app.D_AugmentedTrainingCheckBock.Visible = "on";
                case "random walk"
                    app.D_AugmentationIntensitySlider.Limits = [0, 1];
                    app.D_AugmentationIntensityLabel.Text = "Standard deviation";
                    app.D_AugmentationIntensitySlider.Visible="on";
                    app.D_AugmentationIntensityLabel.Visible="on";
                    app.D_AugmentedTrainingCheckBock.Visible = "on";
                case "global shift"
                    app.D_AugmentationIntensitySlider.Limits = [-100, 100];
                    app.D_AugmentationIntensityLabel.Text = "Shift in % of the max value";
                    app.D_AugmentationIntensitySlider.Visible="on";
                    app.D_AugmentationIntensityLabel.Visible="on";
                    app.D_AugmentedTrainingCheckBock.Visible = "on";
                case "attenuate extremum"
                    app.D_AugmentationIntensitySlider.Limits = [0, 100];
                    app.D_AugmentationIntensityLabel.Text = "Strength of the transformation in %";
                    app.D_AugmentationIntensitySlider.Visible="on";
                    app.D_AugmentationIntensityLabel.Visible="on";
                    app.D_AugmentedTrainingCheckBock.Visible = "on";                    
            end

            if ~app.info_dataLoaded
                return;
            end

            d = uiprogressdlg(app.TSADPlatformUIFigure, Title="Preprocessing Dataset ...", ...
                              Indeterminate="on");
            drawnow; 

            app.runPreprocessing(true);

            close(d);
        end

        % Value changed function: D_AugmentedTrainingCheckBock
        function D_AugmentedTrainingCheckBockValueChanged(app, event)
            if ~app.info_trainDataLoaded
                return;
            end
            
            d = uiprogressdlg(app.TSADPlatformUIFigure, Title="Preprocessing Dataset ...", ...
                              Indeterminate="on");
            drawnow;          
            
            app.runPreprocessing(true);

            close(d);            
        end

        % Button pushed function: T_LoadModelsFromFileButton
        function T_LoadModelsFromFileButtonPushed(app, event)
            f = figure("Renderer", "painters", "Position", [-100 -100 0 0]); % Dummy figure for keeping app in foreground after uigetfile/uiputfile
            [file, path] = uigetfile("*.json", "Select a Configuration File", pwd);
            delete(f);
            
            if ~isequal(file, 0) && ~isequal(path, 0)
                d = uiprogressdlg(app.TSADPlatformUIFigure, Title="Loading Models ...", ...
                                  Indeterminate="on");
                drawnow;

                filePath = fullfile(path, file);
                fid = fopen(filePath);
                raw = fread(fid, inf);
                str = char(raw');
                fclose(fid);
                try
                    models = jsondecode(str);
                    
                    for i = 1:numel(models.models)
                        app.addModelToList(models.models(i).modelOptions);
                    end
                catch
                    uialert(app.TSADPlatformUIFigure, "Invalid config file selected.", ...
                            "Import Error", Icon="error");
                    close(d);
                    return;
                end
                close(d);
            end            
        end

        % Button pushed function: T_ExportConfigButton
        function T_ExportConfigButtonPushed(app, event)
            models = app.T_ModelsListBox.ItemsData;
            
            if isempty(models)
                uialert(app.TSADPlatformUIFigure, "No models found for export.", ...
                        "Export Error", Icon="error");
                return;
            end

            f = figure("Renderer", "painters", "Position", [-100 -100 0 0]); % Dummy figure for keeping app in foreground after uigetfile/uiputfile
            [file, path] = uiputfile("*.json", "Export Model Config", sprintf("tsad_platform_config_%s.json", datestr(now,"mm-dd-yyyy_HH-MM")));
            delete(f);
            if isequal(path, 0)
                uialert(app.TSADPlatformUIFigure, "No valid path selected.", ...
                        "Export Error", Icon="error");
                return;
            end

            allModels.models = models;
            
            filePath = fullfile(path, file);
            fid = fopen(filePath, "w");
            fprintf(fid, "%s", jsonencode(allModels, PrettyPrint=true));
            fclose(fid);

            uialert(app.TSADPlatformUIFigure, "Successfully saved config.", ...
                    "Save Successful", Icon="success");
        end

        % Button pushed function: T_AddModelsManuallyButton
        function T_AddModelsManuallyButtonPushed(app, event)
            app.popup_ModelSelection = ModelSelection(app);
        end

        % Button pushed function: T_QuickLoadAllModelsButton
        function T_QuickLoadAllModelsButtonPushed(app, event)
            % Load all models with their configuration
            fid = fopen("tsad_platform_config_all.json");
            raw = fread(fid, inf);
            str = char(raw');
            fclose(fid);
            models = jsondecode(str);
            
            d = uiprogressdlg(app.TSADPlatformUIFigure, Title="Loading Models ...", ...
                              Indeterminate="on");
            drawnow;

            for i = 1:numel(models.models)
                app.addModelToList(models.models(i).modelOptions);
            end

            close(d);
        end

        % Button pushed function: T_ClearModelsButton
        function T_ClearModelsButtonPushed(app, event)
            app.removeTrainedModelsAndDetections(app.T_ModelsListBox.ItemsData);
            app.updateDetectionPanel();

            app.T_ModelsListBox.Items = {};
            app.T_ModelsListBox.ItemsData = [];
        end

        % Menu selected function: T_ShowModelParametersMenu
        function T_ShowModelParametersMenuSelected(app, event)
            selection = app.T_ModelsListBox.Value;
            if isempty(selection)
                uialert(app.TSADPlatformUIFigure, "No model selected for viewing! Select model first.", ...
                        "Model Viewer Error", Icon="error");
                return;
            end
            if numel(selection) > 1
                uialert(app.TSADPlatformUIFigure, "Can't disply multiple models at once! Please select just a single model.", ...
                        "Model Viewer Error", Icon="error");
                return;
            end

            selectedModel = selection.modelOptions;
            
            d = uiprogressdlg(app.TSADPlatformUIFigure, Title="Loading Model Config ...", ...
                              Indeterminate="on");
            drawnow;

            app.showModelPreview(selectedModel);

            close(d);
        end

        % Menu selected function: T_DeleteMenu
        function T_DeleteMenuSelected(app, event)
            selectedItems = app.T_ModelsListBox.Value;
            
            app.removeTrainedModelsAndDetections(selectedItems);
            app.updateDetectionPanel();

            for i = 1:numel(selectedItems)
                [~, idx] = ismember(selectedItems(i).modelOptions.label, app.T_ModelsListBox.Items);
                app.T_ModelsListBox.Items(idx) = [];
                app.T_ModelsListBox.ItemsData(idx) = [];
            end
        end

        % Button pushed function: T_TrainSelectedModelsButton
        function T_TrainSelectedModelsButtonPushed(app, event)
            if ~app.info_dataLoaded
                uialert(app.TSADPlatformUIFigure, "No data loaded for training!", ...
                        "Training Error", Icon="error");
                return;
            end
            
            models = app.T_ModelsListBox.Value;

            if isempty(app.T_ModelsListBox.Value)
                uialert(app.TSADPlatformUIFigure, "No models selected for training!", ...
                        "Training Error", Icon="error");
                return;
            end

            % If no training data is loaded but a model requiring prior
            % training was selected
            if isempty(app.data_dataTrain)
                for i = 1:numel(models)
                    if ~strcmp(models(i).modelOptions.learningType, "unsupervised")
                        uialert(app.TSADPlatformUIFigure, "You are trying to train a model but the selected dataset doesn't contain training data", ...
                                "Training Error", Icon="error");
                        return;
                    end
                end
            end

            d = uiprogressdlg(app.TSADPlatformUIFigure, Title="Training Models ...", ...
                              Indeterminate="on");
            drawnow;

            app.runTraining(models);
            
            close(d);
            uialert(app.TSADPlatformUIFigure, "Successfully trained models.", ...
                    "Training Success", Icon="success");
        end

        % Button pushed function: T_TrainAllModelsButton
        function T_TrainAllModelsButtonPushed(app, event)
            if ~app.info_dataLoaded
                uialert(app.TSADPlatformUIFigure, "No data loaded for training!", ...
                        "Training Error", Icon="error");
                return;
            end
            
            models = app.T_ModelsListBox.ItemsData;

            if isempty(models)
                uialert(app.TSADPlatformUIFigure, "No models found for training!", ...
                        "Training Error", Icon="error");
                return;
            end
            
            % If no training data is loaded but a model requiring prior
            % training was selected
            if isempty(app.data_dataTrain)
                for i = 1:numel(models)
                    if ~strcmp(models(i).modelOptions.learningType, "unsupervised")
                        uialert(app.TSADPlatformUIFigure, "You are trying to train a model but the selected dataset doesn't contain training data", ...
                                "Training Error", Icon="error");
                        return;
                    end
                end
            end

            d = uiprogressdlg(app.TSADPlatformUIFigure, Title="Training Models ...", ...
                              Indeterminate="on");
            drawnow;
            
            app.runTraining(models);
            
            close(d);
            uialert(app.TSADPlatformUIFigure, "Successfully trained models.", ...
                    "Training Success", Icon="success");
        end

        % Button pushed function: T_OptimizeSelectionButton
        function T_OptimizeSelectionButtonPushed(app, event)
            models = app.T_ModelsListBox.Value;
            if isempty(models)
                uialert(app.TSADPlatformUIFigure, "No models selected for optimization. Select them first.", ...
                        "Optimization Error", Icon="error");
                return;
            end


            if ~isempty(app.popup_Optimization)
                app.popup_Optimization.delete();
            end
            app.popup_Optimization = OptimizationWindow(app, "selection", app.settings_thresholds);
        end

        % Button pushed function: T_OptimizeAllButton
        function T_OptimizeAllButtonPushed(app, event)
            models = app.T_ModelsListBox.ItemsData;
            if isempty(models)
                uialert(app.TSADPlatformUIFigure, "No models found for optimization. Load them first.", ...
                        "Optimization Error", Icon="error");
                return;
            end
            
            if ~isempty(app.popup_Optimization)
                app.popup_Optimization.delete();
            end
            app.popup_Optimization = OptimizationWindow(app, "all", app.settings_thresholds);
        end

        % Menu selected function: T_ChangeScoringFunctionMenu
        function T_ChangeScoringFunctionMenuSelected(app, event)
            models = app.T_ModelsListBox.Value;
            if isempty(models)
                uialert(app.TSADPlatformUIFigure, "No models selected", ...
                        "Scoring Function Update Error", Icon="error");
                return;
            end


            if ~isempty(app.popup_ScoringFunctionSelection)
                app.popup_ScoringFunctionSelection.delete();
            end
            app.popup_ScoringFunctionSelection = ScoringFunctionSelection(app);
        end

        % Menu selected function: T_UpdateStaticThresholdsMenu
        function T_UpdateStaticThresholdsMenuSelected(app, event)
            models = app.T_ModelsListBox.Value;
   
            if isempty(models)
                uialert(app.TSADPlatformUIFigure, "No models selected.", ...
                        "Model Error", Icon="error");
                return;
            end

            d = uiprogressdlg(app.TSADPlatformUIFigure, Title="Updating Static Thresholds ...", ...
                              Indeterminate="on");
            drawnow;
            
            app.updateThresholdsForSelection(models);

            close(d);
            uialert(app.TSADPlatformUIFigure, "Successfully updated static thresholds.", ...
                    "Update Successful", Icon="success");
        end

        % Button pushed function: DE_ImportTrainedModelsButton
        function DE_ImportTrainedModelsButtonPushed(app, event)
            f = figure("Renderer", "painters", "Position", [-100 -100 0 0]); % Dummy figure for keeping app in foreground after uigetfile/uiputfile
            [file, path] = uigetfile("*.mat", "Select a trained Models File", pwd);
            delete(f);

            if ~isequal(file, 0) && ~isequal(path, 0)
                d = uiprogressdlg(app.TSADPlatformUIFigure, Title="Loading trained Models ...", ...
                                  Indeterminate="on");
                drawnow;

                filePath = fullfile(path, file);
                
                try
                    trainedModels_tmp = load(filePath).trainedModels;
                catch
                    close(d);
                    uialert(app.TSADPlatformUIFigure, "Invalid trained models file selected.", ...
                            "Import Error", Icon="error");
                    return;
                end

                trainedModelIds = fieldnames(trainedModels_tmp);
                for i = 1:numel(trainedModelIds)
                    [modelLabel_tmp, modelId_tmp, instanceNumber_tmp] = app.getLabelForModel(trainedModels_tmp.(trainedModelIds{i}).modelOptions.name);
                    app.addModelToList(trainedModels_tmp.(trainedModelIds{i}).modelOptions);
                    
                    modifiedTrainedModel = trainedModels_tmp.(trainedModelIds{i});
                    modifiedTrainedModel.modelOptions.label = modelLabel_tmp;
                    modifiedTrainedModel.modelOptions.id = modelId_tmp;
                    modifiedTrainedModel.modelOptions.instanceNumber = instanceNumber_tmp;

                    trainedModels_tmp = rmfield(trainedModels_tmp, trainedModelIds{i});
                    modifiedTrainedModels.(modelId_tmp) = modifiedTrainedModel;
                end
                
                if exist("modifiedTrainedModels", "var")
                    app.initNewTrainedModels(modifiedTrainedModels);    
                    app.updateDetectionPanel();
                end
                close(d);
            end
        end

        % Button pushed function: DE_ExportTrainedModels
        function DE_ExportTrainedModelsPushed(app, event)
            app.exportTrainedModels();
        end

        % Menu selected function: DE_ShowDetectionMenu
        function DE_ShowDetectionMenuSelected(app, event)
            selection = app.DE_TrainedModelsListBox.Value;
            
            if numel(selection) ~= 1
                return;
            end
            
            testFileVarName = genvarname(app.DE_TestDataFileDropDown.Value);

            if ~isfield(app.storedTestAnomalyScores, testFileVarName)
                return;
            end

            if ~isfield(app.storedTestAnomalyScores.(testFileVarName), selection.id)
                return;
            end

            d = uiprogressdlg(app.TSADPlatformUIFigure, Title="Loading Detection ...", ...
                              Indeterminate="on");
            drawnow;

            YTest = app.storedTestAnomalyScores.(testFileVarName).(selection.id).YTest;
            anomalyScores = app.storedTestAnomalyScores.(testFileVarName).(selection.id).anomalyScores;
            labels = app.storedTestAnomalyScores.(testFileVarName).(selection.id).labels;
            trainedModel = app.trainedModels.(selection.id);
            
            app.updateDetection(trainedModel, anomalyScores, YTest, labels, ...
                                testFileVarName, true, false);

            app.updateDetectionPanel();

            close(d);
        end

        % Value changed function: DE_SortByDropDown
        function DE_SortByDropDownValueChanged(app, event)
            app.sortTrainedModels();
        end

        % Value changed function: DE_TestDataFileDropDown
        function DE_TestDataFileDropDownValueChanged(app, event)
            app.updateTinyDataPreview();
            
            testFileVarName = genvarname(app.DE_TestDataFileDropDown.Value);
            
            if isempty(fieldnames(app.storedTestAnomalyScores)) || ...
                    isempty(app.currentDetection_trainedModel) || ...
                    ~isfield(app.storedTestAnomalyScores, testFileVarName) || ...
                    ~isfield(app.storedTestAnomalyScores.(testFileVarName), app.currentDetection_trainedModel.modelOptions.id)
                app.clearDetectionPlots();
                app.updateDetectionPanel();
                return;
            end
            
            d = uiprogressdlg(app.TSADPlatformUIFigure, Title="Loading Detection ...", ...
                              Indeterminate="on");
            drawnow;

            YTest = app.storedTestAnomalyScores.(testFileVarName).(app.currentDetection_trainedModel.modelOptions.id).YTest;
            anomalyScores = app.storedTestAnomalyScores.(testFileVarName).(app.currentDetection_trainedModel.modelOptions.id).anomalyScores;
            labels = app.storedTestAnomalyScores.(testFileVarName).(app.currentDetection_trainedModel.modelOptions.id).labels;
            
            app.updateDetection(app.currentDetection_trainedModel, ...
                                anomalyScores, ...
                                YTest, ...
                                labels, ...
                                testFileVarName, true, false);
            
            app.updateDetectionPanel();

            close(d);
        end

        % Value changed function: DE_ThresholdDropDown
        function DE_ThresholdDropDownValueChanged(app, event)
            app.toggleDynamicThresholdSliders(app.DE_ThresholdDropDown.Value);
            
            if ~app.info_detectionRun
                return;
            end

            if ~app.info_testDataLoaded
                return;
            end

            d = uiprogressdlg(app.TSADPlatformUIFigure, Title="Updating Detection ...", ...
                              Indeterminate="on");
            drawnow;

            if ~isempty(app.currentDetection_anomalyScores)
                app.updateDetection(app.currentDetection_trainedModel, ...
                                    app.currentDetection_anomalyScores, ...
                                    app.currentDetection_YTest, ...
                                    app.currentDetection_labels, ...
                                    genvarname(app.DE_TestDataFileDropDown.Value), ...
                                    true, false);
            end


            app.updateDetectionPanel();
            
            close(d);
        end

        % Value changed function: DE_AnomalyPaddingSlider
        function DE_AnomalyPaddingSliderValueChanged(app, event)
            app.settings_dynamicThreshold.anomalyPadding = app.DE_AnomalyPaddingSlider.Value;
            
            if ~app.info_detectionRun
                return;
            end

            if ~app.info_testDataLoaded
                return;
            end
            
            d = uiprogressdlg(app.TSADPlatformUIFigure, Title="Updating Detection ...", ...
                              Indeterminate="on");
            drawnow;

            if ~isempty(app.currentDetection_anomalyScores)
                app.updateDetection(app.currentDetection_trainedModel, ...
                                    app.currentDetection_anomalyScores, ...
                                    app.currentDetection_YTest, ...
                                    app.currentDetection_labels, ...
                                    genvarname(app.DE_TestDataFileDropDown.Value), ...
                                    true, false);
            end

            app.updateDetectionPanel();
            
            close(d);
        end

        % Value changed function: DE_WindowSizeSlider
        function DE_WindowSizeSliderValueChanged(app, event)
            app.settings_dynamicThreshold.windowSize = app.DE_WindowSizeSlider.Value;
            
            if ~app.info_detectionRun
                return;
            end
            
            if ~app.info_testDataLoaded
                return;
            end

            d = uiprogressdlg(app.TSADPlatformUIFigure, Title="Updating Detection ...", ...
                              Indeterminate="on");
            drawnow;

            if ~isempty(app.currentDetection_anomalyScores)
                app.updateDetection(app.currentDetection_trainedModel, ...
                                    app.currentDetection_anomalyScores, ...
                                    app.currentDetection_YTest, ...
                                    app.currentDetection_labels, ...
                                    genvarname(app.DE_TestDataFileDropDown.Value), ...
                                    true, false);
            end

            app.updateDetectionPanel();
            
            close(d);
        end

        % Value changed function: DE_ZRangeSlider
        function DE_ZRangeSliderValueChanged(app, event)
            app.settings_dynamicThreshold.zRange = app.DE_ZRangeSlider.Value;
            
            if ~app.info_detectionRun
                return;
            end

            if ~app.info_testDataLoaded
                return;
            end

            d = uiprogressdlg(app.TSADPlatformUIFigure, Title="Updating Detection ...", ...
                              Indeterminate="on");
            drawnow;

            if ~isempty(app.currentDetection_anomalyScores)
                app.updateDetection(app.currentDetection_trainedModel, ...
                                    app.currentDetection_anomalyScores, ...
                                    app.currentDetection_YTest, ...
                                    app.currentDetection_labels, ...
                                    genvarname(app.DE_TestDataFileDropDown.Value), ...
                                    true, false);
            end

            app.updateDetectionPanel();
            
            close(d);
        end

        % Button pushed function: DE_RunDetectionForSelectedButton
        function DE_RunDetectionForSelectedButtonPushed(app, event)
            if ~app.info_testDataLoaded
                uialert(app.TSADPlatformUIFigure, "No test data loaded!", ...
                        "Detection Error", Icon="error");
                return;
            end
            
            if isempty(fieldnames(app.trainedModels))
                uialert(app.TSADPlatformUIFigure, "No trained models found. Train them first on the training panel.", ...
                        "Model Error", Icon="error");
                return;
            end

            models = app.DE_TrainedModelsListBox.Value;

            if isempty(models)
                uialert(app.TSADPlatformUIFigure, "No models selected for detection", ...
                        "Detection Error", Icon="error");
                return;
            end

            d = uiprogressdlg(app.TSADPlatformUIFigure, Title="Running Detection ...", ...
                              Indeterminate="on");
            drawnow;

            if ~app.DE_UpdateThresholdOnlyCheckBox.Value
                app.runDetection(models);
                app.info_detectionRun = true;
            else
                app.updateAppliedThreshold(models);
            end
            
            close(d);

            if ~app.info_dimensionMismatchDetected
                uialert(app.TSADPlatformUIFigure, "Detection finished successfully!", ...
                        "Detection Successful", Icon="success");
            end
        end

        % Button pushed function: DE_RunDetectionForAllButton
        function DE_RunDetectionForAllButtonPushed(app, event)
            if ~app.info_testDataLoaded
                uialert(app.TSADPlatformUIFigure, "No test data loaded!", ...
                        "Detection Error", Icon="error");
                return;
            end
            
            if isempty(fieldnames(app.trainedModels))
                uialert(app.TSADPlatformUIFigure, "No trained models found. Train them first on the training panel.", ...
                        "Model Error", Icon="error");
                return;
            end

            models = app.DE_TrainedModelsListBox.ItemsData;

            if isempty(models)
                uialert(app.TSADPlatformUIFigure, "No models selected for detection", ...
                        "Detection Error", Icon="error");
                return;
            end

            d = uiprogressdlg(app.TSADPlatformUIFigure, Title="Running Detection ...", ...
                              Indeterminate="on");
            drawnow;
            
            if ~app.DE_UpdateThresholdOnlyCheckBox.Value
                app.runDetection(models);
                app.info_detectionRun = true;
            else
                app.updateAppliedThreshold(models);
            end
            
            close(d);
            if ~app.info_dimensionMismatchDetected
                uialert(app.TSADPlatformUIFigure, "Detection finished successfully!", ...
                        "Detection Successful", Icon="success");
            end
        end

        % Button pushed function: DE_RunForAllModelsOnAllFilesButton
        function DE_RunForAllModelsOnAllFilesButtonPushed(app, event)
            if ~app.info_testDataLoaded
                uialert(app.TSADPlatformUIFigure, "No test data loaded!", ...
                        "Detection Error", Icon="error");
                return;
            end
            
            if isempty(fieldnames(app.trainedModels))
                uialert(app.TSADPlatformUIFigure, "No trained models found. Train them first on the training panel.", ...
                        "Model Error", Icon="error");
                return;
            end

            models = app.DE_TrainedModelsListBox.ItemsData;

            if isempty(models)
                uialert(app.TSADPlatformUIFigure, "No models selected for detection", ...
                        "Detection Error", Icon="error");
                return;
            end

            d = uiprogressdlg(app.TSADPlatformUIFigure, Title="Running Detection ...", ...
                              Indeterminate="on");
            drawnow;
            
            if ~app.DE_UpdateThresholdOnlyCheckBox.Value
                app.runDetectionForAllFiles(models);
                app.info_detectionRun = true;
            else
                app.updateAppliedThresholdForAllFiles(models);
            end
            
            close(d);
            if ~app.info_dimensionMismatchDetected
                uialert(app.TSADPlatformUIFigure, "Detection finished successfully!", ...
                        "Detection Successful", Icon="success");
            end
        end

        % Button pushed function: DE_RunForSelectedModelsOnAllFilesButton
        function DE_RunForSelectedModelsOnAllFilesButtonPushed(app, event)
            if ~app.info_testDataLoaded
                uialert(app.TSADPlatformUIFigure, "No test data loaded!", ...
                        "Detection Error", Icon="error");
                return;
            end
            
            if isempty(fieldnames(app.trainedModels))
                uialert(app.TSADPlatformUIFigure, "No trained models found. Train them first on the training panel.", ...
                        "Model Error", Icon="error");
                return;
            end

            models = app.DE_TrainedModelsListBox.Value;

            if isempty(models)
                uialert(app.TSADPlatformUIFigure, "No models selected for detection", ...
                        "Detection Error", Icon="error");
                return;
            end

            d = uiprogressdlg(app.TSADPlatformUIFigure, Title="Running Detection ...", ...
                              Indeterminate="on");
            drawnow;
            
            if ~app.DE_UpdateThresholdOnlyCheckBox.Value
                app.runDetectionForAllFiles(models);
                app.info_detectionRun = true;
            else
                app.updateAppliedThresholdForAllFiles(models);
            end
            
            close(d);
            if ~app.info_dimensionMismatchDetected
                uialert(app.TSADPlatformUIFigure, "Detection finished successfully!", ...
                        "Detection Successful", Icon="success");
            end
        end

        % Button pushed function: DE_ScoresExportTableButton
        function DE_ScoresExportTableButtonPushed(app, event)
            app.saveScoresTable(app.DE_ScoresUITable, "Export Scores", "Scores");
        end

        % Button pushed function: DE_AverageScoresExportTableButton
        function DE_AverageScoresExportTableButtonPushed(app, event)
            app.saveScoresTable(app.DE_AverageScoresUITable, "Export Average Scores", "Average Scores");
        end

        % Button pushed function: DE_CompTimeMetricSelectionButton
        function DE_CompTimeMetricSelectionButtonPushed(app, event)
            app.popup_CompTimeMetricSelection = CompTimeMetricSelection(app, app.compTimeMetrics);
        end

        % Button pushed function: SD_ImportTrainedModelsButton
        function SD_ImportTrainedModelsButtonPushed(app, event)
            f = figure("Renderer", "painters", "Position", [-100 -100 0 0]); % Dummy figure for keeping app in foreground after uigetfile/uiputfile
            [file, path] = uigetfile("*.mat", "Select a trained Models File", pwd);
            delete(f);

            if ~isequal(file, 0) && ~isequal(path, 0)
                d = uiprogressdlg(app.TSADPlatformUIFigure, Title="Loading trained Models ...", ...
                                  Indeterminate="on");
                drawnow;

                filePath = fullfile(path, file);
                
                try
                    trainedModels_tmp = load(filePath).trainedModels;
                catch
                    close(d);
                    uialert(app.TSADPlatformUIFigure, "Invalid trained models file selected.", ...
                            "Import Error", Icon="error");
                    return;
                end

                trainedModelIds = fieldnames(trainedModels_tmp);
                for i = 1:numel(trainedModelIds)
                    [modelLabel_tmp, modelId_tmp, instanceNumber_tmp] = app.getLabelForModel(trainedModels_tmp.(trainedModelIds{i}).modelOptions.name);
                    app.addModelToList(trainedModels_tmp.(trainedModelIds{i}).modelOptions);
                    
                    modifiedTrainedModel = trainedModels_tmp.(trainedModelIds{i});
                    modifiedTrainedModel.modelOptions.label = modelLabel_tmp;
                    modifiedTrainedModel.modelOptions.id = modelId_tmp;
                    modifiedTrainedModel.modelOptions.instanceNumber = instanceNumber_tmp;

                    trainedModels_tmp = rmfield(trainedModels_tmp, trainedModelIds{i});
                    modifiedTrainedModels.(modelId_tmp) = modifiedTrainedModel;
                end
                
                if exist("modifiedTrainedModels", "var")
                    app.initNewTrainedModels(modifiedTrainedModels);    
                    app.updateDetectionPanel();
                end
                close(d);
            end
        end

        % Button pushed function: SD_ExportTrainedModels
        function SD_ExportTrainedModelsPushed(app, event)
            app.exportTrainedModels();
        end

        % Button pushed function: SD_RunDetectionForSelectedButton
        function SD_RunDetectionForSelectedButtonPushed(app, event)
            if ~app.info_testDataLoaded
                uialert(app.TSADPlatformUIFigure, "No test data loaded!", ...
                        "Simulink Detection Error", Icon="error");
                return;
            end

            if isempty(fieldnames(app.trainedModels))
                uialert(app.TSADPlatformUIFigure, "No trained models found. Train them first on the training panel.", ...
                        "Model Error", Icon="error");
                return;
            end
            
            model = app.SD_TrainedModelsListBox.Value;

            if numel(model) > 1
                uialert(app.TSADPlatformUIFigure, "Please select just one model for Simulink detection", ...
                        "Simulink Detection Error", Icon="error");
                return;
            elseif isempty(model)
                uialert(app.TSADPlatformUIFigure, "No model selected for Simulink detection", ...
                        "Simulink Detection Error", Icon="error");
                return;
            end

            modelId = model.id;
            mdl = app.trainedModels.(modelId);

            if ~isfield(mdl, "staticThresholds")
                uialert(app.TSADPlatformUIFigure, "No thresholds available. Some thresholds require the use of an anomalous validation set to be computed (see Preprocessing panel).", ...
                        "Simulink Detection Error", Icon="error");
                return;
            end

            staticThresholds = mdl.staticThresholds;
            
            thresholdItem = getThresholdId(app.SD_ThresholdDropDown.Value);

            if ~isempty(staticThresholds) && isfield(staticThresholds, thresholdItem)
                selectedThreshold = staticThresholds.(thresholdItem);
            else
                uialert(app.TSADPlatformUIFigure, "Threshold not available. Some thresholds require the use of an anomalous validation set to be computed (see Preprocessing panel).", ...
                        "Simulink Detection Error", Icon="error");
                return;
            end

            d = uiprogressdlg(app.TSADPlatformUIFigure, Title="Running Detection on Simulink ...", ...
                              Indeterminate="on");
            drawnow;

            wSize = mdl.modelOptions.hyperparameters.windowSize;
            mdlType = mdl.modelOptions.modelType;
            netPath = app.saveModelForSim(mdl.Mdl, modelId);
            currentMdlLabel = app.trainedModels.(modelId).modelOptions.label;
            app.SD_DetectionShownForLabel.Text = sprintf("Detection shown for: %s", currentMdlLabel);
            if strcmp(mdlType, "predictive")
                simModel = "PredictiveSim";
                load_system(simModel)
                path = strcat(simModel, "/Prediction model");
                set_param(path,"BufferLength",num2str(wSize))
                set_param(path,"NetworkFilePath",netPath)
            else
                simModel = "AutoEncoderPredictSim";
                load_system(simModel)
                path = strcat(simModel, "/Reconstruction model");
                set_param(path,"BufferLength",num2str(wSize))
                set_param(path,"NetworkFilePath",netPath)
            end
            if app.SD_OpenmodelCheckBox.Value == 1
                open(simModel)
            end
            selectedItem = app.SD_FaultyDataSelectionDropDown.Value;
            [~, data_idx] = ismember(selectedItem, app.SD_FaultyDataSelectionDropDown.Items);
            testingData = app.data_dataTest(data_idx);
            testingLabels = app.data_labelsTest(data_idx);
            duration = numel(testingLabels{1});
            assignin("base", "data", timeseries(testingData{1}))
            assignin("base", "labels", timeseries(testingLabels{1}))
            assignin("base", "threshold", selectedThreshold)


            % Plot true data and found anomalies
            simulated = sim(simModel, "StopTime", num2str(duration));
            plot(app.SD_FoundAnomaliesUIAxes, simulated.yout{1}.Values.Data(:,1))
            hold(app.SD_FoundAnomaliesUIAxes, "on")
            plot(app.SD_FoundAnomaliesUIAxes, simulated.yout{1}.Values.Data(:,2), "r")
            hold(app.SD_FoundAnomaliesUIAxes, "off")
            legend(app.SD_FoundAnomaliesUIAxes, ["Data", "Found anomalies"])

            plot(app.SD_TrueAnomaliesUIAxes, simulated.yout{2}.Values.Data(:,1))
            hold(app.SD_TrueAnomaliesUIAxes, "on")
            plot(app.SD_TrueAnomaliesUIAxes, simulated.yout{2}.Values.Data(:,2), "r")
            hold(app.SD_TrueAnomaliesUIAxes, "off")
            legend(app.SD_TrueAnomaliesUIAxes, ["Data", "True anomalies"])

            app.SD_FoundAnomaliesUIAxes.Visible = "on";
            app.SD_TrueAnomaliesUIAxes.Visible = "on";


            close(d);
            uialert(app.TSADPlatformUIFigure, "Detection finished successfully!", ...
                    "Detection Successful", Icon="success");
        end

        % Value changed function: DS_SelectedFileTestSwitchDropDown
        function DS_SelectedFileTestSwitchDropDownValueChanged(app, event)
            app.updatePreviewData("test-switch");
        end

        % Value changed function: DS_SelectedChannelTestSwitchDropDown
        function DS_SelectedChannelTestSwitchDropDownValueChanged(app, event)
            app.updatePreviewData("test-switch");
        end

        % Button pushed function: DS_TrainClassifierButton
        function DS_TrainClassifierButtonPushed(app, event)
            if ~app.D_SplitTestSetForDynamicSwitchCheckBox.Value
                uialert(app.TSADPlatformUIFigure, "Please check the 'Split Test Set for Dynamic Switch' Checkbox on the Preprocessing panel", ...
                        "Data Error", Icon="error");
                return;
            end

            if isempty(fieldnames(app.trainedModels))
                uialert(app.TSADPlatformUIFigure, "No trained models found. Train them first on the training panel.", ...
                        "Model Error", Icon="error");
                return;
            end

            answer = uiconfirm(app.TSADPlatformUIFigure, "Did you already run the detection for all files on the Detection Panel?", ...
                                "Detection Run?", Options={'Yes', 'No'}, DefaultOption='No', CancelOption='No');
            if ~strcmp(answer, "Yes")
                return;
            end
            
            d = uiprogressdlg(app.TSADPlatformUIFigure, Title="Training Dynamic Switch Classifier ...", ...
                              Indeterminate="on");
            drawnow;

            app.ds_bestModels = app.findBestModels(app.DS_MetricDropDown.Value);
            
            fileNamesCell = fieldnames(app.ds_bestModels);
            numTrainFiles = numel(fileNamesCell);
            fileNames = strings(numTrainFiles, 1);
            bestModels = strings(numTrainFiles, 1);
            for i = 1:numTrainFiles
                fileNames(i) = fileNamesCell{i};
                bestModels(i) = app.ds_bestModels.(fileNamesCell{i}).label;
            end
            app.DS_TrainingDataLabelsUITable.Data = [fileNames, bestModels];

            app.ds_classifier = trainDynamicSwitchClassifier(app.data_dataTest, app.ds_bestModels, app.data_fileNamesTest);
            
            close(d);
            uialert(app.TSADPlatformUIFigure, "Successfully trained Dynamic Switch Classifier.", ...
                    "Training Successful", Icon="success");
        end

        % Button pushed function: DS_RunEvaluationsButton
        function DS_RunEvaluationsButtonPushed(app, event)
            if ~app.D_SplitTestSetForDynamicSwitchCheckBox.Value
                uialert(app.TSADPlatformUIFigure, "Please check the 'Split Test Set for Dynamic Switch' Checkbox on the Preprocessing panel", ...
                        "Data Error", Icon="error");
                return;
            end       

            if isempty(fieldnames(app.trainedModels))
                uialert(app.TSADPlatformUIFigure, "No trained models found. Train them first on the training panel.", ...
                        "Model Error", Icon="error");
                return;
            end 
            
            if isempty(app.ds_classifier)
                uialert(app.TSADPlatformUIFigure, "No classifier trained!", ...
                        "Classifier Error", Icon="error");
                return;
            end

            d = uiprogressdlg(app.TSADPlatformUIFigure, Title="Evaluating Dynamic Switch ...", ...
                              Indeterminate="on");
            drawnow;

            fprintf("\n\n### Evaluating dynamic switch ... ###\n\n");
            
            [finalTable, selectedModels] = evaluateDynamicSwitch(app.ds_classifier, ...
                                                                 app.trainedModels, ...
                                                                 app.data_dataTestSwitch, ...
                                                                 app.data_labelsTestSwitch, ...
                                                                 app.data_fileNamesTestSwitch, ...
                                                                 getThresholdId(app.DE_ThresholdDropDown.Value), ...
                                                                 app.settings_dynamicThreshold);

            app.DS_ResultsUITable.Data = finalTable;
            app.DS_ResultsUITable.ColumnName = finalTable.Properties.VariableNames;

            app.DS_TestingDataPredictionsUITable.Data = [app.data_fileNamesTestSwitch, selectedModels];

            removeStyle(app.DS_ResultsUITable);
            app.highlightTableMaxima(app.DS_ResultsUITable, "row-wise");
            
            fprintf("\n\n### Finished evaluating dynamic switch successfully!\n\n");
            close(d);
            uialert(app.TSADPlatformUIFigure, "Successfully finished evaluation.", ...
                    "Evaluation Successful", Icon="success");
        end

        % Button pushed function: DS_ExportButton
        function DS_ExportButtonPushed(app, event)
            app.saveScoresTable(app.DS_ResultsUITable, "Export Dynamic Switch Results", "Dynamic_Switch_Results");
        end

        % Button pushed function: AR_BrowseDatasetButton
        function AR_BrowseDatasetButtonPushed(app, event)
            f = figure("Renderer", "painters", "Position", [-100 -100 0 0]); % Dummy figure for keeping app in foreground after uigetfile/uiputfile
            path = uigetdir(pwd);
            delete(f);
            if ~isequal(path, 0)
                app.AR_DatasetFolderEditField.Value = path;
            end
        end

        % Value changed function: AR_UseAnomalousValidationSetCheckBox
        function AR_UseAnomalousValidationSetCheckBoxValueChanged(app, event)
            if app.AR_UseAnomalousValidationSetCheckBox.Value
                app.AR_RatioTestValLabel.Enable = "on";
                app.AR_RatioTestValEditField.Enable = "on";
            else
                app.AR_RatioTestValLabel.Enable = "off";
                app.AR_RatioTestValEditField.Enable = "off";         
            end
        end

        % Value changed function: AR_AugmentationModeDropDown
        function AR_AugmentationModeDropDownValueChanged(app, event)
            value = app.AR_AugmentationModeDropDown.Value;
            
            %changing the visual of the slider depending on the transformation chosen

            switch value
                case "none"
                    app.AR_IntensitySlider.Visible="off";
                    app.AR_IntensityLabel.Visible="off";
                    app.AR_AugmentedTrainingCheckBock.Visible = "off";
                case "white noise"
                    app.AR_IntensitySlider.Limits = [0, 1];
                    app.AR_IntensityLabel.Text = "Standard deviation";
                    app.AR_IntensitySlider.Visible="on";
                    app.AR_IntensityLabel.Visible="on";
                    app.AR_AugmentedTrainingCheckBock.Visible = "on";
                case "random walk"
                    app.AR_IntensitySlider.Limits = [0, 1];
                    app.AR_IntensityLabel.Text = "Standard deviation";
                    app.AR_IntensitySlider.Visible="on";
                    app.AR_IntensityLabel.Visible="on";
                    app.AR_AugmentedTrainingCheckBock.Visible = "on";
                case "global shift"
                    app.AR_IntensitySlider.Limits = [-100, 100];
                    app.AR_IntensityLabel.Text = "Shift in % of the max value";
                    app.AR_IntensitySlider.Visible="on";
                    app.AR_IntensityLabel.Visible="on";
                    app.AR_AugmentedTrainingCheckBock.Visible = "on";
                case "attenuate extremum"
                    app.AR_IntensitySlider.Limits = [0, 100];
                    app.AR_IntensityLabel.Text = "Strength of the transformation in %";
                    app.AR_IntensitySlider.Visible="on";
                    app.AR_IntensityLabel.Visible="on";
                    app.AR_AugmentedTrainingCheckBock.Visible = "on";
            end
        end

        % Button pushed function: AR_RunEvaluationButton
        function AR_RunEvaluationButtonPushed(app, event)
            datasetPath = app.AR_DatasetFolderEditField.Value;
            
            if isempty(datasetPath)
                uialert(app.TSADPlatformUIFigure, "No valid dataset folder selected!", ...
                        "Dataset Error", Icon="error");
                return;
            end

            if ~isfolder(datasetPath)
                datasetPath = fullfile(pwd, datasetPath);
                if ~isfolder(datasetPath)
                    uialert(app.TSADPlatformUIFigure, "No valid dataset folder selected!", ...
                            "Dataset Error", Icon="error");
                    return;
                end
            end

            % Load all models as added on the Training tab
            models = app.T_ModelsListBox.ItemsData;

            if isempty(models)
                uialert(app.TSADPlatformUIFigure, "No models found for evaluation. Add them on the Training panel.", ...
                        "Model Error", Icon="error");
                return;
            end 
 
            d = uiprogressdlg(app.TSADPlatformUIFigure, Title="Running Auto-Evaluation on Dataset ...", ...
                              Indeterminate="on");
            drawnow;

            if app.AR_UseAnomalousValidationSetCheckBox.Value
                ratioTestVal = app.AR_RatioTestValEditField.Value;
            else
                ratioTestVal = 0;
            end
            preprocMethod = app.AR_PreprocessingMethodDropDown.Value;

            if app.AR_TrainingPlotsCheckBox.Value
                trainingPlots = "training-progress";
            else
                trainingPlots = "none";
            end

            if app.AR_AugmentationModeDropDown.Value ~= "none"
                augmentationEnabled = true;
            else
                augmentationEnabled = false;
            end
            augmentationMode = app.AR_AugmentationModeDropDown.Value;
            augmenteationIntensity = app.AR_IntensitySlider.Value;
            augmentedTrainingEnabled = app.AR_AugmentedTrainingCheckBock.Value;

            finalTable = autoRunWrapper(datasetPath, models, preprocMethod, ...
                                        ratioTestVal, app.settings_thresholds, ...
                                        app.settings_dynamicThreshold, ...
                                        trainingPlots, app.settings_parallelEnabled, ...
                                        augmentationEnabled, ...
                                        augmentationMode, ...
                                        augmenteationIntensity, ...
                                        augmentedTrainingEnabled, ...
                                        app.AR_GetComputationTimeCheckBox.Value);

            app.AR_ResultsUITable.Data = finalTable;
            app.AR_ResultsUITable.ColumnName = finalTable.Properties.VariableNames;

            removeStyle(app.AR_ResultsUITable);
            app.highlightTableMaxima(app.AR_ResultsUITable, "row-wise");
            
            close(d);
            uialert(app.TSADPlatformUIFigure, "Successfully finished evaluating all models on selected dataset. Results saved to Dataset_Sweep_Results folder in the current Matlab folder", ...
                    "Auto Run Successful", Icon="success");
        end

        % Button pushed function: AR_VisualizeDataButton
        function AR_VisualizeDataButtonPushed(app, event)
            datasetPath = app.AR_DatasetFolderEditField.Value;
            
            if isempty(datasetPath)
                uialert(app.TSADPlatformUIFigure, "No valid dataset folder selected!", ...
                        "Dataset Error", Icon="error");
                return;
            end

            if ~isfolder(datasetPath)
                datasetPath = fullfile(pwd, datasetPath);
                if ~isfolder(datasetPath)
                    uialert(app.TSADPlatformUIFigure, "No valid dataset folder selected!", ...
                            "Dataset Error", Icon="error");
                    return;
                end
            end

            if ~strcmp(app.AR_AugmentationModeDropDown.Value, "none")
                augmentationEnabled = true;
            else
                augmentationEnabled = false;
            end

            app.popup_DatasetVisualization = DatasetVisualization(datasetPath, augmentationEnabled, ...
                app.AR_AugmentationModeDropDown.Value, app.AR_IntensitySlider.Value, app.AR_AugmentedTrainingCheckBock.Value);
        end

        % Button pushed function: AR_ExportTableButton
        function AR_ExportTableButtonPushed(app, event)
            app.saveScoresTable(app.AR_ResultsUITable, "Export Auto Run Results", "Auto_Run_Average_Scores");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.TSADPlatformUIFigure = uifigure('Visible', 'off');
            app.TSADPlatformUIFigure.Name = 'TSAD Platform';
            app.TSADPlatformUIFigure.Icon = fullfile(pathToMLAPP, 'media', 'TSADPlatform_Icon.png');
            app.TSADPlatformUIFigure.CloseRequestFcn = createCallbackFcn(app, @TSADPlatformUIFigureCloseRequest, true);
            app.SettingsMenu = uimenu(app.TSADPlatformUIFigure);
            app.SettingsMenu.Text = 'Settings';
            app.ThresholdSelectionMenu = uimenu(app.SettingsMenu);
            app.ThresholdSelectionMenu.MenuSelectedFcn = createCallbackFcn(app, @ThresholdSelectionMenuSelected, true);
            app.ThresholdSelectionMenu.Text = 'Threshold Selection';
            app.DynamicThresholdMenu = uimenu(app.SettingsMenu);
            app.DynamicThresholdMenu.MenuSelectedFcn = createCallbackFcn(app, @DynamicThresholdMenuSelected, true);
            app.DynamicThresholdMenu.Text = 'Dynamic Threshold';
            app.EnableParallelModeMenu = uimenu(app.SettingsMenu);
            app.EnableParallelModeMenu.MenuSelectedFcn = createCallbackFcn(app, @EnableParallelModeMenuSelected, true);
            app.EnableParallelModeMenu.Text = 'Enable Parallel Mode';
            app.MainPanel = uitabgroup(app.TSADPlatformUIFigure);
            app.DatasetPreparationTab = uitab(app.MainPanel);
            app.DatasetPreparationTab.Title = '1.1 Dataset Preparation';
            app.DatasetPreparationPanel = uipanel(app.DatasetPreparationTab);
            app.D_PreprocessedTrainingDatasetPreviewUIAxes = uiaxes(app.DatasetPreparationPanel);
            title(app.D_PreprocessedTrainingDatasetPreviewUIAxes, 'Training Dataset (processed)')
            xlabel(app.D_PreprocessedTrainingDatasetPreviewUIAxes, 'Timestamp')
            ylabel(app.D_PreprocessedTrainingDatasetPreviewUIAxes, 'Value')
            zlabel(app.D_PreprocessedTrainingDatasetPreviewUIAxes, 'Z')
            app.D_PreprocessedTestingDatasetPreviewUIAxes = uiaxes(app.DatasetPreparationPanel);
            title(app.D_PreprocessedTestingDatasetPreviewUIAxes, 'Testing Dataset (processed)')
            xlabel(app.D_PreprocessedTestingDatasetPreviewUIAxes, 'Timestamp')
            ylabel(app.D_PreprocessedTestingDatasetPreviewUIAxes, 'Value')
            zlabel(app.D_PreprocessedTestingDatasetPreviewUIAxes, 'Z')
            app.D_PreprocessedTestValDatasetPreviewUIAxes = uiaxes(app.DatasetPreparationPanel);
            title(app.D_PreprocessedTestValDatasetPreviewUIAxes, 'Anomalous Validation Dataset (processed)')
            xlabel(app.D_PreprocessedTestValDatasetPreviewUIAxes, 'Timestamp')
            ylabel(app.D_PreprocessedTestValDatasetPreviewUIAxes, 'Value')
            zlabel(app.D_PreprocessedTestValDatasetPreviewUIAxes, 'Z')
            app.SelectedChannelLabel_3 = uilabel(app.DatasetPreparationPanel);
            app.SelectedChannelLabel_3.Text = 'Selected Channel:';
            app.D_SelectedChannelTrainDropDown = uidropdown(app.DatasetPreparationPanel);
            app.D_SelectedChannelTrainDropDown.Items = {};
            app.D_SelectedChannelTrainDropDown.ValueChangedFcn = createCallbackFcn(app, @D_SelectedChannelTrainDropDownValueChanged2, true);
            app.D_SelectedChannelTrainDropDown.Value = {};
            app.SelectedFileLabel_3 = uilabel(app.DatasetPreparationPanel);
            app.SelectedFileLabel_3.Text = 'Selected File:';
            app.D_SelectedFileTrainDropDown = uidropdown(app.DatasetPreparationPanel);
            app.D_SelectedFileTrainDropDown.Items = {};
            app.D_SelectedFileTrainDropDown.ValueChangedFcn = createCallbackFcn(app, @D_SelectedFileTrainDropDownValueChanged2, true);
            app.D_SelectedFileTrainDropDown.Value = {};
            app.SelectedChannelLabel_4 = uilabel(app.DatasetPreparationPanel);
            app.SelectedChannelLabel_4.Text = 'Selected Channel:';
            app.D_SelectedChannelTestDropDown = uidropdown(app.DatasetPreparationPanel);
            app.D_SelectedChannelTestDropDown.Items = {};
            app.D_SelectedChannelTestDropDown.ValueChangedFcn = createCallbackFcn(app, @D_SelectedChannelTestDropDownValueChanged2, true);
            app.D_SelectedChannelTestDropDown.Value = {};
            app.SelectedFileLabel_4 = uilabel(app.DatasetPreparationPanel);
            app.SelectedFileLabel_4.Text = 'Selected File:';
            app.D_SelectedFileTestDropDown = uidropdown(app.DatasetPreparationPanel);
            app.D_SelectedFileTestDropDown.Items = {};
            app.D_SelectedFileTestDropDown.ValueChangedFcn = createCallbackFcn(app, @D_SelectedFileTestDropDownValueChanged2, true);
            app.D_SelectedFileTestDropDown.Value = {};
            app.SelectedChannelLabel_5 = uilabel(app.DatasetPreparationPanel);
            app.SelectedChannelLabel_5.Text = 'Selected Channel:';
            app.D_SelectedChannelTestValDropDown = uidropdown(app.DatasetPreparationPanel);
            app.D_SelectedChannelTestValDropDown.Items = {};
            app.D_SelectedChannelTestValDropDown.ValueChangedFcn = createCallbackFcn(app, @D_SelectedChannelTestValDropDownValueChanged, true);
            app.D_SelectedChannelTestValDropDown.Value = {};
            app.SelectedFileLabel_5 = uilabel(app.DatasetPreparationPanel);
            app.SelectedFileLabel_5.Text = 'Selected File:';
            app.D_SelectedFileTestValDropDown = uidropdown(app.DatasetPreparationPanel);
            app.D_SelectedFileTestValDropDown.Items = {};
            app.D_SelectedFileTestValDropDown.ValueChangedFcn = createCallbackFcn(app, @D_SelectedFileTestValDropDownValueChanged, true);
            app.D_SelectedFileTestValDropDown.Value = {};
            app.D_DatasetSelectionPanel = uipanel(app.DatasetPreparationPanel);
            app.D_DatasetSelectionPanel.Title = 'Dataset Selection';
            app.D_DatasetAttributesLabel = uilabel(app.D_DatasetSelectionPanel);
            app.D_DatasetAttributesLabel.Text = 'Dataset Attributes:';
            app.D_DatasetAttributesUITable = uitable(app.D_DatasetSelectionPanel);
            app.D_DatasetAttributesUITable.ColumnName = {'Channel'; 'Minimum'; 'Maximum'; 'Mean'; 'Standard Deviation'};
            app.D_DatasetAttributesUITable.RowName = {};
            app.D_LoadDataButton = uibutton(app.D_DatasetSelectionPanel, 'push');
            app.D_LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @D_LoadDataButtonPushed, true);
            app.D_LoadDataButton.Tooltip = {'Load the selected dataset'};
            app.D_LoadDataButton.Text = 'Load Data';
            app.D_BrowseDatasetButton = uibutton(app.D_DatasetSelectionPanel, 'push');
            app.D_BrowseDatasetButton.ButtonPushedFcn = createCallbackFcn(app, @D_BrowseDatasetButtonPushed, true);
            app.D_BrowseDatasetButton.Text = 'Browse...';
            app.D_DatasetFolderLabel = uilabel(app.D_DatasetSelectionPanel);
            app.D_DatasetFolderLabel.Text = 'Dataset Folder:';
            app.D_DatasetFolderEditField = uieditfield(app.D_DatasetSelectionPanel, 'text');
            app.D_DatasetProcessingPanel = uipanel(app.DatasetPreparationPanel);
            app.D_DatasetProcessingPanel.Title = 'Data Processing';
            app.D_RatioTestValEditField = uieditfield(app.D_DatasetProcessingPanel, 'numeric');
            app.D_RatioTestValEditField.Limits = [0.01 0.99];
            app.D_RatioTestValEditField.ValueChangedFcn = createCallbackFcn(app, @D_RatioTestValEditFieldValueChanged, true);
            app.D_RatioTestValEditField.Enable = 'off';
            app.D_RatioTestValEditField.Value = 0.2;
            app.D_UseAnomalousValidationSetCheckBox = uicheckbox(app.D_DatasetProcessingPanel);
            app.D_UseAnomalousValidationSetCheckBox.ValueChangedFcn = createCallbackFcn(app, @D_UseAnomalousValidationSetCheckBoxValueChanged, true);
            app.D_UseAnomalousValidationSetCheckBox.Text = 'Use anomalous Validation Set';
            app.D_RatioTestValLabel = uilabel(app.D_DatasetProcessingPanel);
            app.D_RatioTestValLabel.Enable = 'off';
            app.D_RatioTestValLabel.Text = 'Ratio:';
            app.D_SplitTestSetForDynamicSwitchCheckBox = uicheckbox(app.D_DatasetProcessingPanel);
            app.D_SplitTestSetForDynamicSwitchCheckBox.ValueChangedFcn = createCallbackFcn(app, @D_SplitTestSetForDynamicSwitchCheckBoxValueChanged, true);
            app.D_SplitTestSetForDynamicSwitchCheckBox.Enable = 'off';
            app.D_SplitTestSetForDynamicSwitchCheckBox.Text = 'Split Test Set for Dynamic Switch';
            app.D_RatioTestSwitchEditField = uieditfield(app.D_DatasetProcessingPanel, 'numeric');
            app.D_RatioTestSwitchEditField.Limits = [0.01 0.99];
            app.D_RatioTestSwitchEditField.ValueChangedFcn = createCallbackFcn(app, @D_RatioTestSwitchEditFieldValueChanged, true);
            app.D_RatioTestSwitchEditField.Enable = 'off';
            app.D_RatioTestSwitchEditField.Value = 0.5;
            app.D_RatioTestSwitchLabel = uilabel(app.D_DatasetProcessingPanel);
            app.D_RatioTestSwitchLabel.Enable = 'off';
            app.D_RatioTestSwitchLabel.Text = 'Ratio:';
            app.D_AugmentedTrainingCheckBock = uicheckbox(app.D_DatasetProcessingPanel);
            app.D_AugmentedTrainingCheckBock.ValueChangedFcn = createCallbackFcn(app, @D_AugmentedTrainingCheckBockValueChanged, true);
            app.D_AugmentedTrainingCheckBock.Visible = 'off';
            app.D_AugmentedTrainingCheckBock.Text = 'Augmented training';
            app.D_AugmentationIntensitySlider = uislider(app.D_DatasetProcessingPanel);
            app.D_AugmentationIntensitySlider.ValueChangedFcn = createCallbackFcn(app, @D_AugmentationIntensitySliderValueChanged, true);
            app.D_AugmentationIntensitySlider.Visible = 'off';
            app.PreprocessingMethodLabel = uilabel(app.D_DatasetProcessingPanel);
            app.PreprocessingMethodLabel.Text = 'Preprocessing Method:';
            app.D_PreprocessingMethodDropDown = uidropdown(app.D_DatasetProcessingPanel);
            app.D_PreprocessingMethodDropDown.Items = {'Rescale [0, 1]', 'Standardize', 'Raw Data'};
            app.D_PreprocessingMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @D_PreprocessingMethodDropDownValueChanged, true);
            app.D_PreprocessingMethodDropDown.Value = 'Rescale [0, 1]';
            app.AugmentationModeDropDownLabel = uilabel(app.D_DatasetProcessingPanel);
            app.AugmentationModeDropDownLabel.Text = 'Augmentation Mode:';
            app.D_AugmentationModeDropDown = uidropdown(app.D_DatasetProcessingPanel);
            app.D_AugmentationModeDropDown.Items = {'none', 'white noise', 'random walk', 'global shift', 'attenuate extremum'};
            app.D_AugmentationModeDropDown.ValueChangedFcn = createCallbackFcn(app, @D_AugmentationModeDropDownValueChanged, true);
            app.D_AugmentationModeDropDown.Value = 'none';
            app.D_AugmentationIntensityLabel = uilabel(app.D_DatasetProcessingPanel);
            app.D_AugmentationIntensityLabel.Visible = 'off';
            app.D_AugmentationIntensityLabel.Text = 'Intensity';
            app.TrainingTab = uitab(app.MainPanel);
            app.TrainingTab.Title = '1.2 Training';
            app.TrainingPanel = uipanel(app.TrainingTab);
            app.T_ModelsPanel = uipanel(app.TrainingPanel);
            app.T_ModelsPanel.Title = 'Models';
            app.T_ModelsListBox = uilistbox(app.T_ModelsPanel);
            app.T_ModelsListBox.Items = {};
            app.T_ModelsListBox.Multiselect = 'on';
            app.T_ModelsListBox.Value = {};
            app.T_AddModelsManuallyButton = uibutton(app.T_ModelsPanel, 'push');
            app.T_AddModelsManuallyButton.ButtonPushedFcn = createCallbackFcn(app, @T_AddModelsManuallyButtonPushed, true);
            app.T_AddModelsManuallyButton.Text = 'Add Models Manually';
            app.T_ClearModelsButton = uibutton(app.T_ModelsPanel, 'push');
            app.T_ClearModelsButton.ButtonPushedFcn = createCallbackFcn(app, @T_ClearModelsButtonPushed, true);
            app.T_ClearModelsButton.Text = 'Clear';
            app.T_QuickLoadAllModelsButton = uibutton(app.T_ModelsPanel, 'push');
            app.T_QuickLoadAllModelsButton.ButtonPushedFcn = createCallbackFcn(app, @T_QuickLoadAllModelsButtonPushed, true);
            app.T_QuickLoadAllModelsButton.Text = 'Quick Load all Models';
            app.T_LoadModelsFromFileButton = uibutton(app.T_ModelsPanel, 'push');
            app.T_LoadModelsFromFileButton.ButtonPushedFcn = createCallbackFcn(app, @T_LoadModelsFromFileButtonPushed, true);
            app.T_LoadModelsFromFileButton.Text = 'Load Models from File';
            app.T_ExportConfigButton = uibutton(app.T_ModelsPanel, 'push');
            app.T_ExportConfigButton.ButtonPushedFcn = createCallbackFcn(app, @T_ExportConfigButtonPushed, true);
            app.T_ExportConfigButton.Text = 'Export Config';
            app.T_TrainingPanel = uipanel(app.TrainingPanel);
            app.T_TrainingPanel.Title = 'Training';
            app.T_TrainSelectedModelsButton = uibutton(app.T_TrainingPanel, 'push');
            app.T_TrainSelectedModelsButton.ButtonPushedFcn = createCallbackFcn(app, @T_TrainSelectedModelsButtonPushed, true);
            app.T_TrainSelectedModelsButton.Text = 'Train Selection';
            app.T_TrainAllModelsButton = uibutton(app.T_TrainingPanel, 'push');
            app.T_TrainAllModelsButton.ButtonPushedFcn = createCallbackFcn(app, @T_TrainAllModelsButtonPushed, true);
            app.T_TrainAllModelsButton.Text = 'Train All';
            app.T_OptimizeSelectionButton = uibutton(app.T_TrainingPanel, 'push');
            app.T_OptimizeSelectionButton.ButtonPushedFcn = createCallbackFcn(app, @T_OptimizeSelectionButtonPushed, true);
            app.T_OptimizeSelectionButton.Text = 'Optimize Selection';
            app.T_TrainingPlotsCheckBox = uicheckbox(app.T_TrainingPanel);
            app.T_TrainingPlotsCheckBox.Text = 'Training Plots';
            app.T_TrainingPlotsCheckBox.Value = true;
            app.T_OptimizeAllButton = uibutton(app.T_TrainingPanel, 'push');
            app.T_OptimizeAllButton.ButtonPushedFcn = createCallbackFcn(app, @T_OptimizeAllButtonPushed, true);
            app.T_OptimizeAllButton.Text = 'Optimize All';
            app.T_ModelPreviewLabel = uilabel(app.TrainingPanel);
            app.T_ModelPreviewLabel.Text = 'Model Preview';
            app.T_PreviewPanel = uipanel(app.TrainingPanel);
            app.T_PreviewUIAxes = uiaxes(app.T_PreviewPanel);
            app.T_PreviewUIAxes.XTick = [];
            app.T_PreviewUIAxes.YTick = [];
            app.T_PreviewUIAxes.Visible = 'off';
            app.T_NoModelLoadedforPreviewLabel = uilabel(app.T_PreviewPanel);
            app.T_NoModelLoadedforPreviewLabel.Text = 'No Model Loaded for Preview';
            app.T_PreviewGeneralInformationUITable = uitable(app.T_PreviewPanel);
            app.T_PreviewGeneralInformationUITable.ColumnName = {'General Information'};
            app.T_PreviewGeneralInformationUITable.RowName = {};
            app.T_PreviewGeneralInformationUITable.Visible = 'off';
            app.T_PreviewHyperparametersUITable = uitable(app.T_PreviewPanel);
            app.T_PreviewHyperparametersUITable.ColumnName = {'Hyperparameters'};
            app.T_PreviewHyperparametersUITable.RowName = {};
            app.T_PreviewHyperparametersUITable.Visible = 'off';
            app.T_PreviewShowingLabel = uilabel(app.T_PreviewPanel);
            app.T_PreviewShowingLabel.Visible = 'off';
            app.T_PreviewShowingLabel.Text = 'Showing: ';
            app.DetectionEvaluationTab = uitab(app.MainPanel);
            app.DetectionEvaluationTab.Title = '1.3 (a) Detection';
            app.DetectionEvaluationPanel = uipanel(app.DetectionEvaluationTab);
            app.DE_TrainedModelsPanel = uipanel(app.DetectionEvaluationPanel);
            app.DE_TrainedModelsPanel.Title = 'Trained Models (ranked)';
            app.DE_TrainedModelsListBox = uilistbox(app.DE_TrainedModelsPanel);
            app.DE_TrainedModelsListBox.Items = {};
            app.DE_TrainedModelsListBox.Multiselect = 'on';
            app.DE_TrainedModelsListBox.Value = {};
            app.DE_ExportTrainedModels = uibutton(app.DE_TrainedModelsPanel, 'push');
            app.DE_ExportTrainedModels.ButtonPushedFcn = createCallbackFcn(app, @DE_ExportTrainedModelsPushed, true);
            app.DE_ExportTrainedModels.Text = 'Export trained Models';
            app.DE_ImportTrainedModelsButton = uibutton(app.DE_TrainedModelsPanel, 'push');
            app.DE_ImportTrainedModelsButton.ButtonPushedFcn = createCallbackFcn(app, @DE_ImportTrainedModelsButtonPushed, true);
            app.DE_ImportTrainedModelsButton.Text = 'Import trained Models';
            app.SortbyLabel = uilabel(app.DE_TrainedModelsPanel);
            app.SortbyLabel.Text = 'Rank by:';
            app.DE_SortByDropDown = uidropdown(app.DE_TrainedModelsPanel);
            app.DE_SortByDropDown.Items = {};
            app.DE_SortByDropDown.ValueChangedFcn = createCallbackFcn(app, @DE_SortByDropDownValueChanged, true);
            app.DE_SortByDropDown.Value = {};
            app.DE_DetectionPanel = uipanel(app.DetectionEvaluationPanel);
            app.DE_DetectionPanel.Title = 'Detection';
            app.DE_TinyDataPreviewUIAxes = uiaxes(app.DE_DetectionPanel);
            app.DE_TinyDataPreviewUIAxes.XTick = [];
            app.DE_TinyDataPreviewUIAxes.YTick = [];
            app.DE_DetectionTabGroup = uitabgroup(app.DE_DetectionPanel);
            app.DE_PlotsTab = uitab(app.DE_DetectionTabGroup);
            app.DE_PlotsTab.Title = 'Plots';
            app.DE_PlotsAnomalyScoresUIAxes = uiaxes(app.DE_PlotsTab);
            title(app.DE_PlotsAnomalyScoresUIAxes, 'Anomaly Scores')
            xlabel(app.DE_PlotsAnomalyScoresUIAxes, 'Timestamp')
            ylabel(app.DE_PlotsAnomalyScoresUIAxes, 'Score')
            zlabel(app.DE_PlotsAnomalyScoresUIAxes, 'Z')
            app.DE_PlotsDataUIAxes = uiaxes(app.DE_PlotsTab);
            title(app.DE_PlotsDataUIAxes, 'Data + Anomalies')
            xlabel(app.DE_PlotsDataUIAxes, 'Timestamp')
            ylabel(app.DE_PlotsDataUIAxes, 'Value')
            zlabel(app.DE_PlotsDataUIAxes, 'Z')
            app.DE_PlotsScoresUITable = uitable(app.DE_PlotsTab);
            app.DE_PlotsScoresUITable.ColumnName = '';
            app.DE_PlotsScoresUITable.RowName = {};
            app.DE_DetectionShownForLabel = uilabel(app.DE_PlotsTab);
            app.DE_DetectionShownForLabel.Text = 'Detection Shown for: None';
            app.DE_DetectionPerformanceMetricsLabel = uilabel(app.DE_PlotsTab);
            app.DE_DetectionPerformanceMetricsLabel.Text = 'Detection Performance Metrics:';
            app.DE_TableTab = uitab(app.DE_DetectionTabGroup);
            app.DE_TableTab.Title = 'Scores (selected File)';
            app.DE_ScoresUITable = uitable(app.DE_TableTab);
            app.DE_ScoresUITable.ColumnName = {'Metric'};
            app.DE_ScoresUITable.RowName = {};
            app.DE_ScoresExportTableButton = uibutton(app.DE_TableTab, 'push');
            app.DE_ScoresExportTableButton.ButtonPushedFcn = createCallbackFcn(app, @DE_ScoresExportTableButtonPushed, true);
            app.DE_ScoresExportTableButton.Text = 'Export Table';
            app.DE_ScoresRowwiseMaximaLabel = uilabel(app.DE_TableTab);
            app.DE_ScoresRowwiseMaximaLabel.Text = 'Rowwise maxima';
            app.DE_ScoresColumnwiseMaximaLabel = uilabel(app.DE_TableTab);
            app.DE_ScoresColumnwiseMaximaLabel.Text = 'Columnwise maxima';
            app.DE_ScoresLegendLabel = uilabel(app.DE_TableTab);
            app.DE_ScoresLegendLabel.Text = 'Legend:';
            app.DE_AverageScoresTab = uitab(app.DE_DetectionTabGroup);
            app.DE_AverageScoresTab.Title = 'Average Scores';
            app.DE_AverageScoresUITable = uitable(app.DE_AverageScoresTab);
            app.DE_AverageScoresUITable.ColumnName = {'Metric'};
            app.DE_AverageScoresUITable.RowName = {};
            app.DE_AverageScoresExportTableButton = uibutton(app.DE_AverageScoresTab, 'push');
            app.DE_AverageScoresExportTableButton.ButtonPushedFcn = createCallbackFcn(app, @DE_AverageScoresExportTableButtonPushed, true);
            app.DE_AverageScoresExportTableButton.Text = 'Export Table';
            app.DE_AverageScoresRowwiseMaximaLabel = uilabel(app.DE_AverageScoresTab);
            app.DE_AverageScoresRowwiseMaximaLabel.Text = 'Rowwise maxima';
            app.DE_AverageScoresColumnwiseMaximaLabel = uilabel(app.DE_AverageScoresTab);
            app.DE_AverageScoresColumnwiseMaximaLabel.Text = 'Columnwise maxima';
            app.DE_AverageScoresLegendLabel = uilabel(app.DE_AverageScoresTab);
            app.DE_AverageScoresLegendLabel.Text = 'Legend:';
            app.DE_ComputationTimeTab = uitab(app.DE_DetectionTabGroup);
            app.DE_ComputationTimeTab.Title = 'Computation Time';
            app.DE_CompTimeUIAxes = uiaxes(app.DE_ComputationTimeTab);
            title(app.DE_CompTimeUIAxes, 'Mean Computation Time per Window')
            xlabel(app.DE_CompTimeUIAxes, 'Models sorted')
            ylabel(app.DE_CompTimeUIAxes, 'Metric value')
            zlabel(app.DE_CompTimeUIAxes, 'Z')
            app.DE_CompTimeMetricSelectionButton = uibutton(app.DE_ComputationTimeTab, 'push');
            app.DE_CompTimeMetricSelectionButton.ButtonPushedFcn = createCallbackFcn(app, @DE_CompTimeMetricSelectionButtonPushed, true);
            app.DE_CompTimeMetricSelectionButton.Text = 'Metric Selection';
            app.DE_RunDetectionForSelectedButton = uibutton(app.DE_DetectionPanel, 'push');
            app.DE_RunDetectionForSelectedButton.ButtonPushedFcn = createCallbackFcn(app, @DE_RunDetectionForSelectedButtonPushed, true);
            app.DE_RunDetectionForSelectedButton.Text = 'Selected Models';
            app.DE_OfflineDetectionLabel = uilabel(app.DE_DetectionPanel);
            app.DE_OfflineDetectionLabel.Text = 'Run Detection For:';
            app.DE_ZRangeLabel = uilabel(app.DE_DetectionPanel);
            app.DE_ZRangeLabel.Enable = 'off';
            app.DE_ZRangeLabel.Text = 'Z-Range:';
            app.DE_WindowSizeLabel = uilabel(app.DE_DetectionPanel);
            app.DE_WindowSizeLabel.Enable = 'off';
            app.DE_WindowSizeLabel.Text = 'Window Size (%):';
            app.DE_AnomalyPaddingLabel = uilabel(app.DE_DetectionPanel);
            app.DE_AnomalyPaddingLabel.Enable = 'off';
            app.DE_AnomalyPaddingLabel.Text = 'Anomaly Padding:';
            app.DE_ZRangeSlider = uislider(app.DE_DetectionPanel);
            app.DE_ZRangeSlider.Limits = [1 10];
            app.DE_ZRangeSlider.ValueChangedFcn = createCallbackFcn(app, @DE_ZRangeSliderValueChanged, true);
            app.DE_ZRangeSlider.Enable = 'off';
            app.DE_ZRangeSlider.Value = 1;
            app.DE_WindowSizeSlider = uislider(app.DE_DetectionPanel);
            app.DE_WindowSizeSlider.Limits = [0 50];
            app.DE_WindowSizeSlider.ValueChangedFcn = createCallbackFcn(app, @DE_WindowSizeSliderValueChanged, true);
            app.DE_WindowSizeSlider.Enable = 'off';
            app.DE_AnomalyPaddingSlider = uislider(app.DE_DetectionPanel);
            app.DE_AnomalyPaddingSlider.Limits = [0 1000];
            app.DE_AnomalyPaddingSlider.ValueChangedFcn = createCallbackFcn(app, @DE_AnomalyPaddingSliderValueChanged, true);
            app.DE_AnomalyPaddingSlider.Enable = 'off';
            app.SelectfaultyDataLabel = uilabel(app.DE_DetectionPanel);
            app.SelectfaultyDataLabel.Text = 'Test Data File:';
            app.DE_TestDataFileDropDown = uidropdown(app.DE_DetectionPanel);
            app.DE_TestDataFileDropDown.Items = {};
            app.DE_TestDataFileDropDown.ValueChangedFcn = createCallbackFcn(app, @DE_TestDataFileDropDownValueChanged, true);
            app.DE_TestDataFileDropDown.Value = {};
            app.ThresholdDropDownLabel = uilabel(app.DE_DetectionPanel);
            app.ThresholdDropDownLabel.Text = 'Threshold:';
            app.DE_ThresholdDropDown = uidropdown(app.DE_DetectionPanel);
            app.DE_ThresholdDropDown.Items = {};
            app.DE_ThresholdDropDown.ValueChangedFcn = createCallbackFcn(app, @DE_ThresholdDropDownValueChanged, true);
            app.DE_ThresholdDropDown.Tooltip = {'The threshold used for converting anomaly scores into binary labels. To enable other thresholds, got to the Settings > Threshold Selection'};
            app.DE_ThresholdDropDown.Value = {};
            app.DE_RunForAllModelsOnAllFilesButton = uibutton(app.DE_DetectionPanel, 'push');
            app.DE_RunForAllModelsOnAllFilesButton.ButtonPushedFcn = createCallbackFcn(app, @DE_RunForAllModelsOnAllFilesButtonPushed, true);
            app.DE_RunForAllModelsOnAllFilesButton.Text = 'All Models on all Files';
            app.DE_RunForSelectedModelsOnAllFilesButton = uibutton(app.DE_DetectionPanel, 'push');
            app.DE_RunForSelectedModelsOnAllFilesButton.ButtonPushedFcn = createCallbackFcn(app, @DE_RunForSelectedModelsOnAllFilesButtonPushed, true);
            app.DE_RunForSelectedModelsOnAllFilesButton.Text = 'Selected Models on all Files';
            app.DE_GetComputationTimeCheckBox = uicheckbox(app.DE_DetectionPanel);
            app.DE_GetComputationTimeCheckBox.Text = 'Get Computation Time';
            app.DE_RunDetectionForAllButton = uibutton(app.DE_DetectionPanel, 'push');
            app.DE_RunDetectionForAllButton.ButtonPushedFcn = createCallbackFcn(app, @DE_RunDetectionForAllButtonPushed, true);
            app.DE_RunDetectionForAllButton.Text = 'All Models';
            app.DE_UpdateThresholdOnlyCheckBox = uicheckbox(app.DE_DetectionPanel);
            app.DE_UpdateThresholdOnlyCheckBox.Text = 'Update Threshold only';
            app.SimulinkDetectionTab = uitab(app.MainPanel);
            app.SimulinkDetectionTab.Title = '1.3 (b) Simulink Detection';
            app.SimulinkDetectionPanel = uipanel(app.SimulinkDetectionTab);
            app.SimulinkDetectionPanel.Enable = 'off';
            app.SD_TrainedModelsPanel = uipanel(app.SimulinkDetectionPanel);
            app.SD_TrainedModelsPanel.Title = 'DNN Models';
            app.SD_TrainedModelsListBox = uilistbox(app.SD_TrainedModelsPanel);
            app.SD_TrainedModelsListBox.Items = {};
            app.SD_TrainedModelsListBox.Value = {};
            app.SD_ExportTrainedModels = uibutton(app.SD_TrainedModelsPanel, 'push');
            app.SD_ExportTrainedModels.ButtonPushedFcn = createCallbackFcn(app, @SD_ExportTrainedModelsPushed, true);
            app.SD_ExportTrainedModels.Text = 'Export trained Models';
            app.SD_ImportTrainedModelsButton = uibutton(app.SD_TrainedModelsPanel, 'push');
            app.SD_ImportTrainedModelsButton.ButtonPushedFcn = createCallbackFcn(app, @SD_ImportTrainedModelsButtonPushed, true);
            app.SD_ImportTrainedModelsButton.Text = 'Import trained Models';
            app.SD_DetectionPanel = uipanel(app.SimulinkDetectionPanel);
            app.SD_DetectionPanel.Title = 'Detection';
            app.SD_FoundAnomaliesUIAxes = uiaxes(app.SD_DetectionPanel);
            title(app.SD_FoundAnomaliesUIAxes, 'Data with true Anomalies')
            xlabel(app.SD_FoundAnomaliesUIAxes, 'Timestamp')
            ylabel(app.SD_FoundAnomaliesUIAxes, 'Value')
            zlabel(app.SD_FoundAnomaliesUIAxes, 'Z')
            app.SD_TrueAnomaliesUIAxes = uiaxes(app.SD_DetectionPanel);
            title(app.SD_TrueAnomaliesUIAxes, 'Data with found Anomalies')
            xlabel(app.SD_TrueAnomaliesUIAxes, 'Timestamp')
            ylabel(app.SD_TrueAnomaliesUIAxes, 'Value')
            zlabel(app.SD_TrueAnomaliesUIAxes, 'Z')
            app.SD_RunDetectionForSelectedButton = uibutton(app.SD_DetectionPanel, 'push');
            app.SD_RunDetectionForSelectedButton.ButtonPushedFcn = createCallbackFcn(app, @SD_RunDetectionForSelectedButtonPushed, true);
            app.SD_RunDetectionForSelectedButton.Text = 'Run Detection';
            app.SD_OpenmodelCheckBox = uicheckbox(app.SD_DetectionPanel);
            app.SD_OpenmodelCheckBox.Text = 'Open model';
            app.ThresholdDropDownLabel_4 = uilabel(app.SD_DetectionPanel);
            app.ThresholdDropDownLabel_4.Text = 'Threshold:';
            app.SD_ThresholdDropDown = uidropdown(app.SD_DetectionPanel);
            app.SD_ThresholdDropDown.Items = {};
            app.SD_ThresholdDropDown.Value = {};
            app.SD_DetectionShownForLabel = uilabel(app.SD_DetectionPanel);
            app.SD_DetectionShownForLabel.Text = 'Detection shown for: None';
            app.SelectfaultyDataLabel_2 = uilabel(app.SD_DetectionPanel);
            app.SelectfaultyDataLabel_2.Text = 'Test Data File:';
            app.SD_FaultyDataSelectionDropDown = uidropdown(app.SD_DetectionPanel);
            app.SD_FaultyDataSelectionDropDown.Items = {};
            app.SD_FaultyDataSelectionDropDown.Value = {};
            app.cDynamicSwitchTab = uitab(app.MainPanel);
            app.cDynamicSwitchTab.Title = '1.3 (c) Dynamic Switch';
            app.DynamicSwitchPanel = uipanel(app.cDynamicSwitchTab);
            app.DE_PreprocessedTestSwitchDatasetPreviewUIAxes = uiaxes(app.DynamicSwitchPanel);
            title(app.DE_PreprocessedTestSwitchDatasetPreviewUIAxes, 'Test Switch Dataset (processed)')
            xlabel(app.DE_PreprocessedTestSwitchDatasetPreviewUIAxes, 'Timestamp')
            ylabel(app.DE_PreprocessedTestSwitchDatasetPreviewUIAxes, 'Value')
            zlabel(app.DE_PreprocessedTestSwitchDatasetPreviewUIAxes, 'Z')
            app.DS_ResultsUITable = uitable(app.DynamicSwitchPanel);
            app.DS_ResultsUITable.ColumnName = '';
            app.DS_ResultsUITable.RowName = {};
            app.DS_ResultsLabel = uilabel(app.DynamicSwitchPanel);
            app.DS_ResultsLabel.Text = 'Results:';
            app.DS_TrainingAndEvaluationPanel = uipanel(app.DynamicSwitchPanel);
            app.DS_TrainingAndEvaluationPanel.Tooltip = {'This panel can be used to train and test a classification network which can select the optimal model for each file of the testing dataset.'};
            app.DS_TrainingAndEvaluationPanel.Title = 'Training and Evaluation';
            app.DS_RunEvaluationsButton = uibutton(app.DS_TrainingAndEvaluationPanel, 'push');
            app.DS_RunEvaluationsButton.ButtonPushedFcn = createCallbackFcn(app, @DS_RunEvaluationsButtonPushed, true);
            app.DS_RunEvaluationsButton.Enable = 'off';
            app.DS_RunEvaluationsButton.Text = 'Run Evaluations';
            app.DS_TrainClassifierButton = uibutton(app.DS_TrainingAndEvaluationPanel, 'push');
            app.DS_TrainClassifierButton.ButtonPushedFcn = createCallbackFcn(app, @DS_TrainClassifierButtonPushed, true);
            app.DS_TrainClassifierButton.Enable = 'off';
            app.DS_TrainClassifierButton.Text = 'Train Classifier';
            app.ScoreforAutolabelingLabel = uilabel(app.DS_TrainingAndEvaluationPanel);
            app.ScoreforAutolabelingLabel.Text = 'Metric for Comparing Models:';
            app.DS_MetricDropDown = uidropdown(app.DS_TrainingAndEvaluationPanel);
            app.DS_MetricDropDown.Items = {};
            app.DS_MetricDropDown.Value = {};
            app.DS_TrainingDataLabelsUITable = uitable(app.DS_TrainingAndEvaluationPanel);
            app.DS_TrainingDataLabelsUITable.ColumnName = {'File'; 'Best Model'};
            app.DS_TrainingDataLabelsUITable.RowName = {};
            app.DS_TrainingDataLabelsLabel = uilabel(app.DS_TrainingAndEvaluationPanel);
            app.DS_TrainingDataLabelsLabel.Text = 'Training Data Labels';
            app.DS_TestingDataPredictionsUITable = uitable(app.DS_TrainingAndEvaluationPanel);
            app.DS_TestingDataPredictionsUITable.ColumnName = {'File'; 'Best Model'};
            app.DS_TestingDataPredictionsUITable.RowName = {};
            app.DS_TestingDataPredictionsLabel = uilabel(app.DS_TrainingAndEvaluationPanel);
            app.DS_TestingDataPredictionsLabel.Text = 'Testing Data Predictions';
            app.DS_ExportButton = uibutton(app.DynamicSwitchPanel, 'push');
            app.DS_ExportButton.ButtonPushedFcn = createCallbackFcn(app, @DS_ExportButtonPushed, true);
            app.DS_ExportButton.Text = 'Export Table';
            app.SelectedChannelLabel_6 = uilabel(app.DynamicSwitchPanel);
            app.SelectedChannelLabel_6.Text = 'Selected Channel:';
            app.DS_SelectedChannelTestSwitchDropDown = uidropdown(app.DynamicSwitchPanel);
            app.DS_SelectedChannelTestSwitchDropDown.Items = {};
            app.DS_SelectedChannelTestSwitchDropDown.ValueChangedFcn = createCallbackFcn(app, @DS_SelectedChannelTestSwitchDropDownValueChanged, true);
            app.DS_SelectedChannelTestSwitchDropDown.Value = {};
            app.SelectedFileLabel_6 = uilabel(app.DynamicSwitchPanel);
            app.SelectedFileLabel_6.Text = 'Selected File:';
            app.DS_SelectedFileTestSwitchDropDown = uidropdown(app.DynamicSwitchPanel);
            app.DS_SelectedFileTestSwitchDropDown.Items = {};
            app.DS_SelectedFileTestSwitchDropDown.ValueChangedFcn = createCallbackFcn(app, @DS_SelectedFileTestSwitchDropDownValueChanged, true);
            app.DS_SelectedFileTestSwitchDropDown.Value = {};
            app.AutoRunTab = uitab(app.MainPanel);
            app.AutoRunTab.Title = '2. Auto Run';
            app.AutoRunPanel = uipanel(app.AutoRunTab);
            app.AR_AutoTrainingAndEvaluationPanel = uipanel(app.AutoRunPanel);
            app.AR_AutoTrainingAndEvaluationPanel.Title = 'Auto Training and Evaluation';
            app.AR_RunEvaluationButton = uibutton(app.AR_AutoTrainingAndEvaluationPanel, 'push');
            app.AR_RunEvaluationButton.ButtonPushedFcn = createCallbackFcn(app, @AR_RunEvaluationButtonPushed, true);
            app.AR_RunEvaluationButton.Text = 'Run Evaluation';
            app.AR_BrowseDatasetButton = uibutton(app.AR_AutoTrainingAndEvaluationPanel, 'push');
            app.AR_BrowseDatasetButton.ButtonPushedFcn = createCallbackFcn(app, @AR_BrowseDatasetButtonPushed, true);
            app.AR_BrowseDatasetButton.Text = 'Browse...';
            app.AR_DatasetFolderLabel = uilabel(app.AR_AutoTrainingAndEvaluationPanel);
            app.AR_DatasetFolderLabel.Text = 'Dataset Folder:';
            app.AR_DatasetFolderEditField = uieditfield(app.AR_AutoTrainingAndEvaluationPanel, 'text');
            app.AR_RatioTestValEditField = uieditfield(app.AR_AutoTrainingAndEvaluationPanel, 'numeric');
            app.AR_RatioTestValEditField.Limits = [0.01 0.99];
            app.AR_RatioTestValEditField.Enable = 'off';
            app.AR_RatioTestValEditField.Value = 0.5;
            app.AR_UseAnomalousValidationSetCheckBox = uicheckbox(app.AR_AutoTrainingAndEvaluationPanel);
            app.AR_UseAnomalousValidationSetCheckBox.ValueChangedFcn = createCallbackFcn(app, @AR_UseAnomalousValidationSetCheckBoxValueChanged, true);
            app.AR_UseAnomalousValidationSetCheckBox.Text = 'Use anomalous Validation Set';
            app.AR_RatioTestValLabel = uilabel(app.AR_AutoTrainingAndEvaluationPanel);
            app.AR_RatioTestValLabel.Enable = 'off';
            app.AR_RatioTestValLabel.Text = 'Ratio:';
            app.AR_TrainingPlotsCheckBox = uicheckbox(app.AR_AutoTrainingAndEvaluationPanel);
            app.AR_TrainingPlotsCheckBox.Text = 'Training Plots';
            app.AR_AugmentedTrainingCheckBock = uicheckbox(app.AR_AutoTrainingAndEvaluationPanel);
            app.AR_AugmentedTrainingCheckBock.Visible = 'off';
            app.AR_AugmentedTrainingCheckBock.Text = 'Augmented training';
            app.AR_IntensitySlider = uislider(app.AR_AutoTrainingAndEvaluationPanel);
            app.AR_IntensitySlider.Visible = 'off';
            app.AR_IntensityLabel = uilabel(app.AR_AutoTrainingAndEvaluationPanel);
            app.AR_IntensityLabel.Visible = 'off';
            app.AR_IntensityLabel.Text = 'Intensity';
            app.PreprocessingMethodLabel_2 = uilabel(app.AR_AutoTrainingAndEvaluationPanel);
            app.PreprocessingMethodLabel_2.Text = 'Preprocessing Method:';
            app.AR_PreprocessingMethodDropDown = uidropdown(app.AR_AutoTrainingAndEvaluationPanel);
            app.AR_PreprocessingMethodDropDown.Items = {'Rescale [0, 1]', 'Standardize', 'Raw Data'};
            app.AR_PreprocessingMethodDropDown.Value = 'Rescale [0, 1]';
            app.AugmentationModeDropDownLabel_2 = uilabel(app.AR_AutoTrainingAndEvaluationPanel);
            app.AugmentationModeDropDownLabel_2.Text = 'Augmentation Mode:';
            app.AR_AugmentationModeDropDown = uidropdown(app.AR_AutoTrainingAndEvaluationPanel);
            app.AR_AugmentationModeDropDown.Items = {'none', 'white noise', 'random walk', 'global shift', 'attenuate extremum'};
            app.AR_AugmentationModeDropDown.ValueChangedFcn = createCallbackFcn(app, @AR_AugmentationModeDropDownValueChanged, true);
            app.AR_AugmentationModeDropDown.Value = 'none';
            app.AR_VisualizeDataButton = uibutton(app.AR_AutoTrainingAndEvaluationPanel, 'push');
            app.AR_VisualizeDataButton.ButtonPushedFcn = createCallbackFcn(app, @AR_VisualizeDataButtonPushed, true);
            app.AR_VisualizeDataButton.Text = 'Visualize Data';
            app.AR_GetComputationTimeCheckBox = uicheckbox(app.AR_AutoTrainingAndEvaluationPanel);
            app.AR_GetComputationTimeCheckBox.Text = 'Get Computation Time';
            app.AR_ResultsUITable = uitable(app.AutoRunPanel);
            app.AR_ResultsUITable.ColumnName = '';
            app.AR_ResultsUITable.RowName = {};
            app.AR_ResultsLabel = uilabel(app.AutoRunPanel);
            app.AR_ResultsLabel.Text = 'Average Scores (See Folder Auto_Run_Results for Rest):';
            app.AR_ExportTableButton = uibutton(app.AutoRunPanel, 'push');
            app.AR_ExportTableButton.ButtonPushedFcn = createCallbackFcn(app, @AR_ExportTableButtonPushed, true);
            app.AR_ExportTableButton.Text = 'Export Table';
            app.T_ContextMenu = uicontextmenu(app.TSADPlatformUIFigure);
            app.T_DeleteMenu = uimenu(app.T_ContextMenu);
            app.T_DeleteMenu.MenuSelectedFcn = createCallbackFcn(app, @T_DeleteMenuSelected, true);
            app.T_DeleteMenu.Text = 'Delete';
            app.T_ShowModelParametersMenu = uimenu(app.T_ContextMenu);
            app.T_ShowModelParametersMenu.MenuSelectedFcn = createCallbackFcn(app, @T_ShowModelParametersMenuSelected, true);
            app.T_ShowModelParametersMenu.Text = 'Show Model Parameters';
            app.T_ChangeScoringFunctionMenu = uimenu(app.T_ContextMenu);
            app.T_ChangeScoringFunctionMenu.MenuSelectedFcn = createCallbackFcn(app, @T_ChangeScoringFunctionMenuSelected, true);
            app.T_ChangeScoringFunctionMenu.Text = 'Change Scoring Function';
            app.T_UpdateStaticThresholdsMenu = uimenu(app.T_ContextMenu);
            app.T_UpdateStaticThresholdsMenu.MenuSelectedFcn = createCallbackFcn(app, @T_UpdateStaticThresholdsMenuSelected, true);
            app.T_UpdateStaticThresholdsMenu.Text = 'Update Static Thresholds';
            % Assign app.T_ContextMenu
            app.T_ModelsListBox.ContextMenu = app.T_ContextMenu;
            app.DE_ContextMenu = uicontextmenu(app.TSADPlatformUIFigure);
            app.DE_ShowDetectionMenu = uimenu(app.DE_ContextMenu);
            app.DE_ShowDetectionMenu.MenuSelectedFcn = createCallbackFcn(app, @DE_ShowDetectionMenuSelected, true);
            app.DE_ShowDetectionMenu.Text = 'Show Detection';
            % Assign app.DE_ContextMenu
            app.DE_TrainedModelsListBox.ContextMenu = app.DE_ContextMenu;
            % Show the figure after all components are created
            app.TSADPlatformUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TSADPlatform

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.TSADPlatformUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.TSADPlatformUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TSADPlatformUIFigure)
        end
    end
end